classdef BadChansGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BadChansGUIUIFigure   matlab.ui.Figure
        MainTitleLabel        matlab.ui.control.Label
        UITable               matlab.ui.control.Table
        SAVEButton            matlab.ui.control.Button
        LoadParametersButton  matlab.ui.control.Button
        ClearButton           matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
    end


    properties (Access = private)
        CallingApp   % Main app
        DialogApp
        Conditions_Order
        Conditions_OrderCell
        CurrentPath
        Date_Start
        SavePath
        BSfactors
        WSfactors
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Position, Path, ParamPath, BSfactors, WSfactors, DirectoryCond, FileCond, ProcessData, Date_Start, SavePath)
            % Retrieve current path
            app.CurrentPath=Path;
            
            % Retrieve path where to save results
            app.SavePath=SavePath;
            
            % Retrieve time and date 
            app.Date_Start = Date_Start;
            
            % Retrieve matrix of included data
            ProcessData = logical(cell2mat(ProcessData));

            % Position the GUI at the center of the screen
            app.BadChansGUIUIFigure.Position=Position;
            
            % Number of between-subject factors
            app.BSfactors=BSfactors;
            
            % Number of within-subject factors
            app.WSfactors=WSfactors;
            
            % Reading data from the Conditions Order excel file
            app.Conditions_Order=readtable([DirectoryCond FileCond]);
            app.Conditions_OrderCell=table2cell(app.Conditions_Order);
            
            % Editing baseline table data 
            % Only BS
            if WSfactors == 0 && BSfactors > 0
                app.UITable.Data=repmat({''},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-1);
            % Only WS 
            elseif WSfactors > 0 && BSfactors == 0
                app.UITable.Data=repmat({''},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-1);
            %  WS + BS
            else
                app.UITable.Data=repmat({''},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-BSfactors-1);
            end
            app.UITable.Data(cellfun(@(x) isempty(x),app.Conditions_OrderCell(:,2:end-BSfactors)))={[]};
            
            % Restricting to included data 
            app.UITable.Data(ProcessData==0) = {[]};
            
            app.UITable.ColumnName=app.Conditions_Order.Properties.VariableNames(2:end-BSfactors);
%             Numbers = app.Conditions_OrderCell(:,1);
%             app.UITable.RowName=strcat(repmat({'Subj '},[length(Numbers),1]),cellfun(@(x) num2str(x),Numbers,'UniformOutput',false));
            app.UITable.RowName=app.Conditions_OrderCell(:,1);
            app.UITable.ColumnEditable=true(1,size(ProcessData,2)); 

        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            % Possibility to load existing parameters
            [BadChansFile,BadChansPath] = uigetfile([app.SavePath '\Parameters\' 'GUIBadChans.mat']);
            load([BadChansFile BadChansPath], 'GUIBadChans.mat')
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)

            % Saving properties in a .mat file     
            BadChansList=app.UITable.Data;
            save([app.SavePath '\Parameters\' app.Date_Start '\GUIBadChans.mat'],'BadChansList');
        
            % delete options dialog
            delete(app)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Editing baseline table data
            % Only BS
            if app.WSfactors == 0 && app.BSfactors > 0
                app.UITable.Data=repmat({''},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-1);
            % Only WS 
            elseif app.WSfactors > 0 && app.BSfactors == 0
                app.UITable.Data=repmat({''},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-1);
            %  WS + BS
            else
                app.UITable.Data=repmat({''},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-app.BSfactors-1);
            end
            app.UITable.Data(cellfun(@(x) isempty(x),app.Conditions_OrderCell(:,2:end-app.BSfactors)))={''};
        end

        % Callback function
        function SelectallButtonPushed(app, event)
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BadChansGUIUIFigure = uifigure('Visible', 'off');
            app.BadChansGUIUIFigure.Name = 'SubjectsGUI';
            app.MainTitleLabel = uilabel(app.BadChansGUIUIFigure);
            app.MainTitleLabel.Text = 'List of Bad Channels';
            app.UITable = uitable(app.BadChansGUIUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.SAVEButton = uibutton(app.BadChansGUIUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.LoadParametersButton = uibutton(app.BadChansGUIUIFigure, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.ClearButton = uibutton(app.BadChansGUIUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.TextArea = uitextarea(app.BadChansGUIUIFigure);
            app.TextArea.Value = {'In the table below, include the list of channels that should be interpolated for each subject (and condition). '; 'The channel list in each box should be formatted accordingly: 1,4,5,15 (i.e. separated by a comma ",").'; 'You can either define channels by their numbers (e.g. 1,4,12) or by their labels (e.g. CPz,Cz,P1).'};
            % Show the figure after all components are created
            app.BadChansGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BadChansGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BadChansGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BadChansGUIUIFigure)
        end
    end
end