classdef DesignGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DesignUIFigure                  matlab.ui.Figure
        MainTitleLabel                  matlab.ui.control.Label
        WithinsubjectsFactorsPanel      matlab.ui.container.Panel
        NumberoffactorsEditFieldLabel   matlab.ui.control.Label
        NumberoffactorsEditField        matlab.ui.control.NumericEditField
        NumberoflevelsEditFieldLabel    matlab.ui.control.Label
        NumberoflevelsEditField         matlab.ui.control.NumericEditField
        Factor1EditFieldLabel           matlab.ui.control.Label
        Factor1EditField                matlab.ui.control.EditField
        F1Level1EditFieldLabel          matlab.ui.control.Label
        F1Level1EditField               matlab.ui.control.EditField
        F1Level2EditFieldLabel          matlab.ui.control.Label
        F1Level2EditField               matlab.ui.control.EditField
        F1Level3EditFieldLabel          matlab.ui.control.Label
        F1Level3EditField               matlab.ui.control.EditField
        WithinSwitch                    matlab.ui.control.Switch
        BetweensubjectsFactorsPanel     matlab.ui.container.Panel
        NumberoffactorsEditField_2Label  matlab.ui.control.Label
        NumberoffactorsEditField_2      matlab.ui.control.NumericEditField
        NumberoflevelsEditField_2Label  matlab.ui.control.Label
        NumberoflevelsEditField_2       matlab.ui.control.NumericEditField
        Factor1EditField_2Label         matlab.ui.control.Label
        Factor1EditField_2              matlab.ui.control.EditField
        Factor2EditField_2Label         matlab.ui.control.Label
        Factor2EditField_2              matlab.ui.control.EditField
        F1Level1EditField_2Label        matlab.ui.control.Label
        F1Level1EditField_2             matlab.ui.control.EditField
        F1Level2EditField_2Label        matlab.ui.control.Label
        F1Level2EditField_2             matlab.ui.control.EditField
        F1Level3EditField_2Label        matlab.ui.control.Label
        F1Level3EditField_2             matlab.ui.control.EditField
        F2Level1EditField_2Label        matlab.ui.control.Label
        F2Level1EditField_2             matlab.ui.control.EditField
        F2Level2EditField_2Label        matlab.ui.control.Label
        F2Level2EditField_2             matlab.ui.control.EditField
        F2Level3EditField_2Label        matlab.ui.control.Label
        F2Level3EditField_2             matlab.ui.control.EditField
        BetweenSwitch                   matlab.ui.control.Switch
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        DIRECTORYButton                 matlab.ui.control.Button
        ALLOCATIONButton                matlab.ui.control.Button
        SAVEButton                      matlab.ui.control.Button
        SUBJECTSButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        CallingApp   % Main app
        % Defining variables to be shared inside and outside the App
        % Setting the default directory as the current directory
        CurrentPWD = pwd;
        SavePath = pwd;
        FilesPath
        Preloaded
        
        % Creating variables that will hold the values of design parameters
        % Within factors
        WithinFactors=cell(1,2);
        WithinLevels=cell(2,3);

        % Between factors
        BetweenFactors=cell(1,2);
        BetweenLevels=cell(2,3);
        WSfactors
        BSfactors
        DesignStructure=cell(4,1);
        
        % If parameters were loaded in the MAIN GUI
        ParamPath = [];
        
        % Informations related to excel conditions assignment files
        DirectoryCond
        FileCond 
        
        % Time and date 
        Date_Start
        
    end
    
    properties (Access = private)
        % To establish connection with dialog box% To establish connection with dialog box
        DialogApp 
    end
    
    methods (Access = public)
        
        function updateDesignGUI(app,DirectoryCond, FileCond)
            app.DirectoryCond = DirectoryCond;
            app.FileCond = FileCond; 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Position, ParamPath, Date_Start)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = MainApp;
            
            % Retrieve time and date 
            app.Date_Start = Date_Start;
            
            % Position the GUI at the center of the screen
            app.DesignUIFigure.Position=Position;
            
            % Path of loaded parameters (if any)
            app.ParamPath = ParamPath;
            
            % Loading parameters if they exist
            if ~isempty(ParamPath)
                GuiDesign = load([ParamPath '\GUIDesign.mat']);
                app.SavePath = GuiDesign.SavePath;
                app.FilesPath = GuiDesign.FilesPath;
                app.DirectoryCond=GuiDesign.DirectoryCond;
                app.FileCond=GuiDesign.FileCond; 
                
                % Will update all the GUI that are linked
                app.Preloaded = 1;
                
                % Enabling all buttons
                app.ALLOCATIONButton.Enable = 'on';
                app.SUBJECTSButton.Enable = 'on';
                app.SAVEButton.Enable = 'on';
                
                % WITHIN
                if ~isempty(GuiDesign.WithinFactors{1})
                    % Storing locally for further use
                    app.WithinFactors=GuiDesign.WithinFactors;
                    app.WithinLevels=GuiDesign.WithinLevels;
                    
                    % Switch and Factor 1
                    app.WithinSwitch.Value = 'On';
                    app.Factor1EditField.Value = GuiDesign.WithinFactors{1};
                    app.Factor1EditField.Enable='on';
                    app.NumberoffactorsEditField.Value = 1;
                    app.NumberoffactorsEditField.Enable='on';
                    
                    % Levels
                    if ~isempty(GuiDesign.WithinLevels{1,1})
                        
                        % Number of levels defined
                        CountLevels = cellfun(@(x) ~isempty(x),GuiDesign.WithinLevels(1,:));

                        % Level 1
                        app.F1Level1EditField.Value = GuiDesign.WithinLevels{1,1};
                        app.F1Level1EditField.Enable = 'on';
                        
                        % Level 2 & 3
                        if ~isempty(GuiDesign.WithinLevels{1,2})
                            app.F1Level2EditField.Value = GuiDesign.WithinLevels{1,2};
                            app.F1Level2EditField.Enable = 'on';
                            if ~isempty(GuiDesign.WithinLevels{1,3})
                                app.F1Level3EditField.Value = GuiDesign.WithinLevels{1,3};
                                app.F1Level3EditField.Enable = 'on';
                            end
                        end
                        
                        app.NumberoflevelsEditField.Value = nnz(CountLevels);
                        app.NumberoflevelsEditField.Enable='on';
                        
                    end
                    
                    if ~isempty(GuiDesign.WithinFactors{2})
                        
                        % Factor 2
                        app.Factor2EditField.Value = GuiDesign.WithinFactors{2};
                        app.Factor2EditField.Enable='on';
                        app.NumberoffactorsEditField.Value = 2;
                        
                        % Levels                   
                        if ~isempty(GuiDesign.WithinLevels{2,2})
    
                            % Level 1
                            app.F1Level1EditField.Value = GuiDesign.WithinLevels{2,1};
                            app.F1Level1EditField.Enable = 'on';
                            
                            % Level 2 & 3
                            if ~isempty(GuiDesign.WithinLevels{2,2})
                                app.F1Level2EditField.Value = GuiDesign.WithinLevels{2,2};
                                app.F1Level2EditField.Enable = 'on';
                                if ~isempty(GuiDesign.WithinLevels{2,3})
                                    app.F1Level3EditField.Value = GuiDesign.WithinLevels{2,3};
                                    app.F1Level3EditField.Enable = 'on';
                                end
                            end
                        end
                        
                    end
                end
                
                % BETWEEN
                % Factor 1
                if ~isempty(GuiDesign.BetweenFactors{1})
                    % Storing locally for further use
                    app.BetweenFactors=GuiDesign.BetweenFactors;
                    app.BetweenLevels=GuiDesign.BetweenLevels;


                    app.BetweenSwitch.Value = 'On';
                    app.Factor1EditField_2.Value = GuiDesign.BetweenFactors{1};
                    app.Factor1EditField_2.Enable='on';
                    app.NumberoffactorsEditField_2.Value = 1;
                    app.NumberoffactorsEditField_2.Enable='on';
                    
                    % Levels
                    if ~isempty(GuiDesign.BetweenLevels{1,1})
                        
                        % Number of levels defined
                        CountLevels = cellfun(@(x) ~isempty(x),GuiDesign.BetweenLevels(1,:));

                        % Level 1
                        app.F1Level1EditField_2.Value = GuiDesign.BetweenLevels{1,1};
                        app.F1Level1EditField_2.Enable = 'on';
                        
                        % Level 2 & 3
                        if ~isempty(GuiDesign.BetweenLevels{1,2})
                            app.F1Level2EditField_2.Value = GuiDesign.BetweenLevels{1,2};
                            app.F1Level2EditField_2.Enable = 'on';
                            if ~isempty(GuiDesign.BetweenLevels{1,3})
                                app.F1Level3EditField_2.Value = GuiDesign.BetweenLevels{1,3};
                                app.F1Level3EditField_2.Enable = 'on';
                            end
                        end
                        
                        app.NumberoflevelsEditField_2.Value = nnz(CountLevels);
                        app.NumberoflevelsEditField_2.Enable='on';
                        
                    end
                    
                    % Factor 2
                    if ~isempty(GuiDesign.BetweenFactors{2})
                        
                        %app.Factor2EditField_2.Value = GuiDesign.WithinFactors{2};
                        app.NumberoffactorsEditField_2.Value = 2;
                        app.Factor2EditField_2.Enable='on';
                        
                        % Levels                   
                        if ~isempty(GuiDesign.BetweenLevels{2,2})
    
                            % Level 1
                            app.F1Level1EditField_2.Value = GuiDesign.BetweenLevels{2,1};
                            app.F1Level1EditField_2.Enable = 'on';
                            
                            % Level 2 & 3
                            if ~isempty(GuiDesign.BetweenLevels{2,2})
                                app.F1Level2EditField_2.Value = GuiDesign.BetweenLevels{2,2};
                                app.F1Level2EditField_2.Enable = 'on';
                                if ~isempty(GuiDesign.BetweenLevels{2,3})
                                    app.F1Level3EditField_2.Value = GuiDesign.BetweenLevels{2,3};
                                    app.F1Level3EditField_2.Enable = 'on';
                                end
                            end
                        end
                        
                    end
                end
            end
        end

        % Value changed function: WithinSwitch
        function WithinSwitchValueChanged(app, event)
            value = app.WithinSwitch.Value;
            
            % Change status of factors/levels depending on on/off switch
            if strcmp(value,'On')
                app.NumberoffactorsEditField.Enable='on';
                app.NumberoflevelsEditField.Enable='on';
                app.Factor1EditField.Enable='on';
                app.F1Level1EditField.Enable='on';
            else 
                app.NumberoffactorsEditField.Enable='off';
                app.NumberoflevelsEditField.Enable='off';
                app.NumberoffactorsEditField.Value=0; % changed 1 to 0
                app.NumberoflevelsEditField.Value=0;
                % Factor 1
                app.Factor1EditField.Enable='off';
                app.F1Level1EditField.Enable='off';
                app.F1Level2EditField.Enable='off';
                app.F1Level3EditField.Enable='off';
                % Factor 2
%                 app.Factor2EditField.Enable='off';
%                 app.F2Level1EditField.Enable='off';
%                 app.F2Level2EditField.Enable='off';
%                 app.F2Level3EditField.Enable='off';
%                 app.NumberoffactorsEditField_2.Value=1;
%                 app.NumberoflevelsEditField_2.Value=1;
            end
        end

        % Value changed function: NumberoffactorsEditField
        function NumberoffactorsEditFieldValueChanged(app, event)
            value = app.NumberoffactorsEditField.Value;
            
            % Enabling factors fields depending on number of factors defined in value
            if strcmp(app.NumberoffactorsEditField.Enable,'on')
%                 if value==2
%                     % app.Factor1EditField.Enable='on';
%                     app.Factor2EditField.Enable='on';
%                     app.F2Level1EditField.Enable='on';
%                 end
            elseif strcmp(app.NumberoffactorsEditField.Enable,'off')
                app.Factor1EditField.Enable='off';
%                 app.Factor2EditField.Enable='off';
            end
        end

        % Value changed function: NumberoflevelsEditField
        function NumberoflevelsEditFieldValueChanged(app, event)
            value = app.NumberoflevelsEditField.Value;
            
            % Enabling levels fields depending on number of levels defined in value
             if strcmp(app.NumberoflevelsEditField.Enable,'on')
                if value==1 % && strcmp(app.Factor2EditField.Enable,'on')
%                     % Factor 2
%                     app.F2Level1EditField.Enable='on';
                elseif value==2
                    % Factor 1
                    app.F1Level2EditField.Enable='on';
                    
%                     % Factor 2
%                     if strcmp(app.Factor2EditField.Enable,'on')
%                         app.F2Level1EditField.Enable='on';
%                         app.F2Level2EditField.Enable='on';
%                     end
                elseif value==3
                    % Factor 1
                    app.F1Level2EditField.Enable='on';
                    app.F1Level3EditField.Enable='on';
                    
                    % Factor 2
%                     if strcmp(app.Factor2EditField.Enable,'on')
%                         app.F2Level1EditField.Enable='on';
%                         app.F2Level2EditField.Enable='on';
%                         app.F2Level3EditField.Enable='on';
%                     end
                end
            elseif strcmp(app.NumberoflevelsEditField.Enable,'off')
                    % Factors 1 
                    app.F1Level2EditField.Enable='off';
                    app.F1Level3EditField.Enable='off';
%                     app.F2Level1EditField.Enable='off';
%                     app.F2Level2EditField.Enable='off';
%                     app.F2Level3EditField.Enable='off';
            end
        end

        % Value changed function: NumberoffactorsEditField_2
        function NumberoffactorsEditField_2ValueChanged(app, event)
            value = app.NumberoffactorsEditField_2.Value;
            
             % Enabling factors fields depending on number of factors defined in value
            if strcmp(app.NumberoffactorsEditField_2.Enable,'on')
                if value==2
                    app.Factor2EditField_2.Enable='on';
%                     app.F2Level1EditField_2.Enable='on';
                end
            elseif strcmp(app.NumberoffactorsEditField_2.Enable,'off')
                app.Factor1EditField_2.Enable='off';
%                 app.Factor2EditField_2.Enable='off';
            end
        end

        % Value changed function: BetweenSwitch
        function BetweenSwitchValueChanged(app, event)
            value = app.BetweenSwitch.Value;
            
            % Change status of factors/levels depending on on/off switch
            if strcmp(value,'On')
                app.NumberoffactorsEditField_2.Enable='on';
                app.NumberoflevelsEditField_2.Enable='on';
                app.Factor1EditField_2.Enable='on';
                app.F1Level1EditField_2.Enable='on';
            else 
                app.NumberoffactorsEditField_2.Enable='off';
                app.NumberoflevelsEditField_2.Enable='off';
                app.NumberoffactorsEditField_2.Value=0; % changed 1 to 0
                app.NumberoflevelsEditField_2.Value=0;
                % Factor 1
                app.Factor1EditField_2.Enable='off';
                app.F1Level1EditField_2.Enable='off';
                app.F1Level2EditField_2.Enable='off';
                app.F1Level3EditField_2.Enable='off';
                % Factor 2
                app.Factor2EditField_2.Enable='off';
                app.F2Level1EditField_2.Enable='off';
                app.F2Level2EditField_2.Enable='off';
                app.F2Level3EditField_2.Enable='off';
            end
        end

        % Value changed function: NumberoflevelsEditField_2
        function NumberoflevelsEditField_2ValueChanged(app, event)
            value = app.NumberoflevelsEditField_2.Value;
            
                       % Enabling levels fields depending on number of levels defined in value
             if strcmp(app.NumberoflevelsEditField_2.Enable,'on')
                if value==1 && strcmp(app.Factor2EditField_2.Enable,'on')
                    % Factor 2
                    app.F2Level1EditField_2.Enable='on';
                elseif value==2
                    % Factor 1
                    app.F1Level2EditField_2.Enable='on';
                    
                    % Factor 2
                    if strcmp(app.Factor2EditField_2.Enable,'on')
                        app.F2Level1EditField_2.Enable='on';
                        app.F2Level2EditField_2.Enable='on';
                    end
                elseif value==3
                    % Factor 1
                    app.F1Level2EditField_2.Enable='on';
                    app.F1Level3EditField_2.Enable='on';
                    
                    % Factor 2
                    if strcmp(app.Factor2EditField_2.Enable,'on')
                        app.F2Level1EditField_2.Enable='on';
                        app.F2Level2EditField_2.Enable='on';
                        app.F2Level3EditField_2.Enable='on';
                    end
                end
            elseif strcmp(app.NumberoflevelsEditField_2.Enable,'off')
                    % Factors 1 & 2
                    app.F1Level2EditField_2.Enable='off';
                    app.F1Level3EditField_2.Enable='off';
                    app.F2Level1EditField_2.Enable='off';
                    app.F2Level2EditField_2.Enable='off';
                    app.F2Level3EditField_2.Enable='off';
             end
            
        end

        % Button pushed function: DIRECTORYButton
        function DIRECTORYButtonPushed(app, event)
            % Will load a number of times equivalent to the number of between-subj factors
            Pos = 1;
            if strcmpi(app.NumberoffactorsEditField_2.Enable,'On')
                for k=1:app.NumberoffactorsEditField_2.Value
                    for l=1:app.NumberoflevelsEditField_2.Value
                        app.FilesPath{Pos}=uigetdir('title',...
                            sprintf('Indicate the location of the folder corresponding to the Between-Subject Level: %s',...
                        app.BetweenLevels{k,l}));
                        Pos = Pos + 1;
                    end
                end
            else
                % If no between-subject factors 
                  app.FilesPath{1}=uigetdir('title','Indicate the location of your data folder'); 
            end
            
            
            % Retrieving save folder
            app.SavePath=uigetdir('title',['Indicate the location of the folder WHERE TO SAVE YOUR RESULTS'...
                newline ' (If same as current folder, close this prompt!)']);
                            
            if isempty(app.ParamPath)
                % Builds the folder structure in the CurrentPath if doesn't exist yet
                % Results of Group analyses
                mkdir ([app.SavePath '\STUDY\' app.Date_Start])
                mkdir ([app.SavePath '\STUDY\' app.Date_Start '\MicroStates\'])
                
                % Excel folder template
                mkdir ([app.SavePath '\Excel\' app.Date_Start])
                
                % Figures and matrices folders
                mkdir ([app.SavePath '\Exports\' app.Date_Start])
                mkdir ([app.SavePath '\Exports\' app.Date_Start '\SleepNoSleep\'])
                mkdir ([app.SavePath '\Exports\' app.Date_Start '\SourceLocalisation\'])
                mkdir ([app.SavePath '\Exports\' app.Date_Start '\TopoplotsDipFit\'])
                mkdir ([app.SavePath '\Exports\' app.Date_Start '\TopoplotsDipFit\LabelDipoles\'])
                mkdir ([app.SavePath '\Exports\' app.Date_Start '\Topoplots\'])
                mkdir ([app.SavePath '\Exports\' app.Date_Start '\MicroStatesSegment\'])
                mkdir ([app.SavePath '\Exports\' app.Date_Start '\ICClust\'])
                
                % Parameters
                mkdir ([app.SavePath '\Parameters\' app.Date_Start]) 
            end
            
            % Activate next button
            app.ALLOCATIONButton.Enable = 'on';
        end

        % Value changed function: Factor1EditField
        function Factor1EditFieldValueChanged(app, event)
            % Storing value in properties
            app.WithinFactors{1} = app.Factor1EditField.Value;
        end

        % Callback function
        function Factor2EditFieldValueChanged(app, event)
            app.WithinFactors{2} = app.Factor2EditField.Value;
        end

        % Value changed function: F1Level1EditField
        function F1Level1EditFieldValueChanged(app, event)
            app.WithinLevels{1,1} = app.F1Level1EditField.Value;
        end

        % Value changed function: F1Level2EditField
        function F1Level2EditFieldValueChanged(app, event)
            app.WithinLevels{1,2} = app.F1Level2EditField.Value;
        end

        % Value changed function: F1Level3EditField
        function F1Level3EditFieldValueChanged(app, event)
            app.WithinLevels{1,3} = app.F1Level3EditField.Value;
        end

        % Callback function
        function F2Level1EditFieldValueChanged(app, event)
            app.WithinLevels{2,1} = app.F2Level1EditField.Value;
        end

        % Callback function
        function F2Level2EditFieldValueChanged(app, event)
            app.WithinLevels{2,2} = app.F2Level2EditField.Value;
        end

        % Callback function
        function F2Level3EditFieldValueChanged(app, event)
            app.WithinLevels{2,3} = app.F2Level3EditField.Value;
        end

        % Value changed function: Factor1EditField_2
        function Factor1EditField_2ValueChanged(app, event)
            app.BetweenFactors{1,1} = app.Factor1EditField_2.Value;
        end

        % Value changed function: Factor2EditField_2
        function Factor2EditField_2ValueChanged(app, event)
            app.BetweenFactors{1,2} = app.Factor2EditField_2.Value;
        end

        % Value changed function: F1Level1EditField_2
        function F1Level1EditField_2ValueChanged(app, event)
            app.BetweenLevels{1,1} = app.F1Level1EditField_2.Value;
        end

        % Value changed function: F1Level2EditField_2
        function F1Level2EditField_2ValueChanged(app, event)
            app.BetweenLevels{1,2} = app.F1Level2EditField_2.Value;
        end

        % Value changed function: F1Level3EditField_2
        function F1Level3EditField_2ValueChanged(app, event)
            app.BetweenLevels{1,3} = app.F1Level3EditField_2.Value;
        end

        % Value changed function: F2Level1EditField_2
        function F2Level1EditField_2ValueChanged(app, event)
            app.BetweenLevels{2,1} = app.F2Level1EditField_2.Value;
        end

        % Value changed function: F2Level2EditField_2
        function F2Level2EditField_2ValueChanged(app, event)
            app.BetweenLevels{2,2} = app.F2Level2EditField_2.Value;
        end

        % Value changed function: F2Level3EditField_2
        function F2Level3EditField_2ValueChanged(app, event)
            app.BetweenLevels{2,3} = app.F2Level3EditField_2.Value;
        end

        % Button pushed function: ALLOCATIONButton
        function ALLOCATIONButtonPushed(app, event)
            % Builds the full design structure to send to the PartAssignmentGUI
            % While also removing empty cells
            app.DesignStructure{1,1}=app.WithinFactors(~cellfun('isempty',app.WithinFactors));
            for k=1:length(app.WithinFactors) % Will account for more than 1 factor
                app.DesignStructure{2,k}=app.WithinLevels(k,~cellfun('isempty',app.WithinLevels(k,:)));
            end
            app.DesignStructure{3,1}=app.BetweenFactors(~cellfun('isempty',app.BetweenFactors));
            for k=1:length(app.BetweenFactors) 
                app.DesignStructure{4,k}=app.BetweenLevels(k,~cellfun('isempty',app.BetweenLevels(k,:)));
            end
            
            % Activate next button
            app.SUBJECTSButton.Enable = 'on';
            
            % Open the options dialog and pass inputs
            app.DialogApp = PartAssignmentGUI(app,app.CurrentPWD,app.DesignUIFigure.Position,app.DesignStructure, app.Preloaded,app.Date_Start,app.SavePath);
        end

        % Button pushed function: SUBJECTSButton
        function SUBJECTSButtonPushed(app, event)
            % Activate next button
            app.SAVEButton.Enable = 'on';
            
            % Sending indications regarding number of Between-subject factors
            if strcmpi(app.BetweenSwitch.Value,'On')
                app.BSfactors = app.NumberoffactorsEditField_2.Value;
            else
                app.BSfactors = 0;
            end
            if strcmpi(app.WithinSwitch.Value,'On')
               app.WSfactors = app.NumberoffactorsEditField.Value; 
            else
                app.WSfactors = 0;
            end
                
            % Open the options dialog and pass inputs
            app.DialogApp = SubjectsGUI(app.DesignUIFigure.Position,app.CurrentPWD,app.ParamPath,app.BSfactors,app.WSfactors,...
                app.DirectoryCond,app.FileCond,app.Date_Start,app.SavePath,0);
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
           
            % Saving properties in a .mat file 
            SavePath=app.SavePath;
            WithinFactors=app.WithinFactors;
            BetweenFactors=app.BetweenFactors;
            WSfactors = app.WSfactors;
            BSfactors = app.BSfactors;
            WithinLevels=app.WithinLevels;
            BetweenLevels=app.BetweenLevels;
            CurrentPWD=app.CurrentPWD;
            FilesPath=app.FilesPath;
            DirectoryCond=app.DirectoryCond;
            FileCond=app.FileCond; 
            
            save([app.SavePath '\Parameters\' app.Date_Start '\GUIDesign.mat'],'WithinFactors',...
                'BetweenFactors','WithinLevels','BetweenLevels','CurrentPWD',...
                'FilesPath','SavePath','WSfactors','BSfactors','DirectoryCond','FileCond'); 
            
            % Send values back to main window by calling its public function
            updateMainGUI(app.CallingApp,app.WSfactors,app.BSfactors,app.FilesPath,app.SavePath);
            
            % delete options dialog
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DesignUIFigure = uifigure('Visible', 'off');
            app.DesignUIFigure.Name = 'Design';
            app.MainTitleLabel = uilabel(app.DesignUIFigure);
            app.MainTitleLabel.Text = 'Design';
            app.WithinsubjectsFactorsPanel = uipanel(app.DesignUIFigure);
            app.WithinsubjectsFactorsPanel.Title = 'Within-subjects Factor(s)';
            app.NumberoffactorsEditFieldLabel = uilabel(app.WithinsubjectsFactorsPanel);
            app.NumberoffactorsEditFieldLabel.Text = 'Number of factors';
            app.NumberoffactorsEditField = uieditfield(app.WithinsubjectsFactorsPanel, 'numeric');
            app.NumberoffactorsEditField.Limits = [0 1];
            app.NumberoffactorsEditField.ValueDisplayFormat = '%.0f';
            app.NumberoffactorsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoffactorsEditFieldValueChanged, true);
            app.NumberoffactorsEditField.Enable = 'off';
            app.NumberoflevelsEditFieldLabel = uilabel(app.WithinsubjectsFactorsPanel);
            app.NumberoflevelsEditFieldLabel.Text = 'Number of levels';
            app.NumberoflevelsEditField = uieditfield(app.WithinsubjectsFactorsPanel, 'numeric');
            app.NumberoflevelsEditField.Limits = [0 3];
            app.NumberoflevelsEditField.ValueDisplayFormat = '%.0f';
            app.NumberoflevelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoflevelsEditFieldValueChanged, true);
            app.NumberoflevelsEditField.Enable = 'off';
            app.Factor1EditFieldLabel = uilabel(app.WithinsubjectsFactorsPanel);
            app.Factor1EditFieldLabel.Text = 'Factor 1';
            app.Factor1EditField = uieditfield(app.WithinsubjectsFactorsPanel, 'text');
            app.Factor1EditField.ValueChangedFcn = createCallbackFcn(app, @Factor1EditFieldValueChanged, true);
            app.Factor1EditField.Enable = 'off';
            app.F1Level1EditFieldLabel = uilabel(app.WithinsubjectsFactorsPanel);
            app.F1Level1EditFieldLabel.Text = 'F1: Level 1';
            app.F1Level1EditField = uieditfield(app.WithinsubjectsFactorsPanel, 'text');
            app.F1Level1EditField.ValueChangedFcn = createCallbackFcn(app, @F1Level1EditFieldValueChanged, true);
            app.F1Level1EditField.Enable = 'off';
            app.F1Level2EditFieldLabel = uilabel(app.WithinsubjectsFactorsPanel);
            app.F1Level2EditFieldLabel.Text = 'F1: Level 2';
            app.F1Level2EditField = uieditfield(app.WithinsubjectsFactorsPanel, 'text');
            app.F1Level2EditField.ValueChangedFcn = createCallbackFcn(app, @F1Level2EditFieldValueChanged, true);
            app.F1Level2EditField.Enable = 'off';
            app.F1Level3EditFieldLabel = uilabel(app.WithinsubjectsFactorsPanel);
            app.F1Level3EditFieldLabel.Text = 'F1: Level 3';
            app.F1Level3EditField = uieditfield(app.WithinsubjectsFactorsPanel, 'text');
            app.F1Level3EditField.ValueChangedFcn = createCallbackFcn(app, @F1Level3EditFieldValueChanged, true);
            app.F1Level3EditField.Enable = 'off';
            app.WithinSwitch = uiswitch(app.WithinsubjectsFactorsPanel, 'slider');
            app.WithinSwitch.ValueChangedFcn = createCallbackFcn(app, @WithinSwitchValueChanged, true);
            app.BetweensubjectsFactorsPanel = uipanel(app.DesignUIFigure);
            app.BetweensubjectsFactorsPanel.Title = 'Between-subjects Factor(s)';
            app.NumberoffactorsEditField_2Label = uilabel(app.BetweensubjectsFactorsPanel);
            app.NumberoffactorsEditField_2Label.Text = 'Number of factors';
            app.NumberoffactorsEditField_2 = uieditfield(app.BetweensubjectsFactorsPanel, 'numeric');
            app.NumberoffactorsEditField_2.Limits = [0 1];
            app.NumberoffactorsEditField_2.ValueDisplayFormat = '%.0f';
            app.NumberoffactorsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberoffactorsEditField_2ValueChanged, true);
            app.NumberoffactorsEditField_2.Enable = 'off';
            app.NumberoflevelsEditField_2Label = uilabel(app.BetweensubjectsFactorsPanel);
            app.NumberoflevelsEditField_2Label.Text = 'Number of levels';
            app.NumberoflevelsEditField_2 = uieditfield(app.BetweensubjectsFactorsPanel, 'numeric');
            app.NumberoflevelsEditField_2.Limits = [0 3];
            app.NumberoflevelsEditField_2.ValueDisplayFormat = '%.0f';
            app.NumberoflevelsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberoflevelsEditField_2ValueChanged, true);
            app.NumberoflevelsEditField_2.Enable = 'off';
            app.Factor1EditField_2Label = uilabel(app.BetweensubjectsFactorsPanel);
            app.Factor1EditField_2Label.Text = 'Factor 1';
            app.Factor1EditField_2 = uieditfield(app.BetweensubjectsFactorsPanel, 'text');
            app.Factor1EditField_2.ValueChangedFcn = createCallbackFcn(app, @Factor1EditField_2ValueChanged, true);
            app.Factor1EditField_2.Enable = 'off';
            app.Factor2EditField_2Label = uilabel(app.BetweensubjectsFactorsPanel);
            app.Factor2EditField_2Label.Visible = 'off';
            app.Factor2EditField_2Label.Text = 'Factor 2';
            app.Factor2EditField_2 = uieditfield(app.BetweensubjectsFactorsPanel, 'text');
            app.Factor2EditField_2.ValueChangedFcn = createCallbackFcn(app, @Factor2EditField_2ValueChanged, true);
            app.Factor2EditField_2.Enable = 'off';
            app.Factor2EditField_2.Visible = 'off';
            app.F1Level1EditField_2Label = uilabel(app.BetweensubjectsFactorsPanel);
            app.F1Level1EditField_2Label.Text = 'F1: Level 1';
            app.F1Level1EditField_2 = uieditfield(app.BetweensubjectsFactorsPanel, 'text');
            app.F1Level1EditField_2.ValueChangedFcn = createCallbackFcn(app, @F1Level1EditField_2ValueChanged, true);
            app.F1Level1EditField_2.Enable = 'off';
            app.F1Level2EditField_2Label = uilabel(app.BetweensubjectsFactorsPanel);
            app.F1Level2EditField_2Label.Text = 'F1: Level 2';
            app.F1Level2EditField_2 = uieditfield(app.BetweensubjectsFactorsPanel, 'text');
            app.F1Level2EditField_2.ValueChangedFcn = createCallbackFcn(app, @F1Level2EditField_2ValueChanged, true);
            app.F1Level2EditField_2.Enable = 'off';
            app.F1Level3EditField_2Label = uilabel(app.BetweensubjectsFactorsPanel);
            app.F1Level3EditField_2Label.Text = 'F1: Level 3';
            app.F1Level3EditField_2 = uieditfield(app.BetweensubjectsFactorsPanel, 'text');
            app.F1Level3EditField_2.ValueChangedFcn = createCallbackFcn(app, @F1Level3EditField_2ValueChanged, true);
            app.F1Level3EditField_2.Enable = 'off';
            app.F2Level1EditField_2Label = uilabel(app.BetweensubjectsFactorsPanel);
            app.F2Level1EditField_2Label.Visible = 'off';
            app.F2Level1EditField_2Label.Text = 'F2: Level 1';
            app.F2Level1EditField_2 = uieditfield(app.BetweensubjectsFactorsPanel, 'text');
            app.F2Level1EditField_2.ValueChangedFcn = createCallbackFcn(app, @F2Level1EditField_2ValueChanged, true);
            app.F2Level1EditField_2.Enable = 'off';
            app.F2Level1EditField_2.Visible = 'off';
            app.F2Level2EditField_2Label = uilabel(app.BetweensubjectsFactorsPanel);
            app.F2Level2EditField_2Label.Visible = 'off';
            app.F2Level2EditField_2Label.Text = 'F2: Level 2';
            app.F2Level2EditField_2 = uieditfield(app.BetweensubjectsFactorsPanel, 'text');
            app.F2Level2EditField_2.ValueChangedFcn = createCallbackFcn(app, @F2Level2EditField_2ValueChanged, true);
            app.F2Level2EditField_2.Enable = 'off';
            app.F2Level2EditField_2.Visible = 'off';
            app.F2Level3EditField_2Label = uilabel(app.BetweensubjectsFactorsPanel);
            app.F2Level3EditField_2Label.Visible = 'off';
            app.F2Level3EditField_2Label.Text = 'F2: Level 3';
            app.F2Level3EditField_2 = uieditfield(app.BetweensubjectsFactorsPanel, 'text');
            app.F2Level3EditField_2.ValueChangedFcn = createCallbackFcn(app, @F2Level3EditField_2ValueChanged, true);
            app.F2Level3EditField_2.Enable = 'off';
            app.F2Level3EditField_2.Visible = 'off';
            app.BetweenSwitch = uiswitch(app.BetweensubjectsFactorsPanel, 'slider');
            app.BetweenSwitch.ValueChangedFcn = createCallbackFcn(app, @BetweenSwitchValueChanged, true);
            app.TextAreaLabel = uilabel(app.DesignUIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.DesignUIFigure);
            app.TextArea.Value = {'Instructions:'; ''; '1) Define the numbers of factors/levels and their respective names  [NO SPACES ALLOWED]'; ''; '2) Press on DIRECTORY to define the most upper path containing your files.'; ''; '2) Press on ALLOCATION to define which subject is assigned to which condition/group.'; ''; '3) Press on SUBJECTS to define which subject to include in preprocessing/analysis.'; ''; '3) Press on SAVE to go back to the main window.'};
            app.DIRECTORYButton = uibutton(app.DesignUIFigure, 'push');
            app.DIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @DIRECTORYButtonPushed, true);
            app.DIRECTORYButton.Text = 'DIRECTORY';
            app.ALLOCATIONButton = uibutton(app.DesignUIFigure, 'push');
            app.ALLOCATIONButton.ButtonPushedFcn = createCallbackFcn(app, @ALLOCATIONButtonPushed, true);
            app.ALLOCATIONButton.Enable = 'off';
            app.ALLOCATIONButton.Text = 'ALLOCATION';
            app.SAVEButton = uibutton(app.DesignUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.SUBJECTSButton = uibutton(app.DesignUIFigure, 'push');
            app.SUBJECTSButton.ButtonPushedFcn = createCallbackFcn(app, @SUBJECTSButtonPushed, true);
            app.SUBJECTSButton.Enable = 'off';
            app.SUBJECTSButton.Text = 'SUBJECTS';
            % Show the figure after all components are created
            app.DesignUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DesignGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DesignUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DesignUIFigure)
        end
    end
end