classdef EEGParamGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGParamGUIUIFigure             matlab.ui.Figure
        MainTitleLabel                  matlab.ui.control.Label
        BasicsPanel                     matlab.ui.container.Panel
        FilesNameEditFieldLabel         matlab.ui.control.Label
        FilesNameEditField              matlab.ui.control.EditField
        TextArea_2                      matlab.ui.control.TextArea
        ChannelnumbersEditFieldLabel    matlab.ui.control.Label
        ChannelnumbersEditField         matlab.ui.control.EditField
        SamplingRateHzEditField_2Label  matlab.ui.control.Label
        SamplingRateHzEditField         matlab.ui.control.NumericEditField
        BeginningEditFieldLabel         matlab.ui.control.Label
        BeginningEditField              matlab.ui.control.NumericEditField
        EndLabel                        matlab.ui.control.Label
        EndEditField                    matlab.ui.control.NumericEditField
        TextArea_3                      matlab.ui.control.TextArea
        ExtensionDropDownLabel          matlab.ui.control.Label
        ExtensionDropDown               matlab.ui.control.DropDown
        TextArea_5                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        SAVEButton                      matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        Switch                          matlab.ui.control.Switch
        ExportPanel                     matlab.ui.container.Panel
        FilteredLabel                   matlab.ui.control.Label
        ArtifactrejectedLabel           matlab.ui.control.Label
        ICAcomputedLabel                matlab.ui.control.Label
        ICArejectedLabel                matlab.ui.control.Label
        ExportFilt                      matlab.ui.control.Switch
        ExportClean                     matlab.ui.control.Switch
        ExportICAED                     matlab.ui.control.Switch
        ExportICARej                    matlab.ui.control.Switch
        DatasetssetLabel                matlab.ui.control.Label
        FilesbdfLabel                   matlab.ui.control.Label
        PreprocessedLabel               matlab.ui.control.Label
        ExportBDF                       matlab.ui.control.Switch
        TextArea_6                      matlab.ui.control.TextArea
    end


    properties (Access = public)
        CallingApp   % Main app
        FileNames 
        SamplingRate
        Channels
        CurrentPath
        Beginning
        End
        Date_Start
        SavePath
    end

    properties (Access = private)
        DialogApp 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Position, Path, ParamPath, FilesPath, Date_Start, SavePath)
            % Retrieve current path
            app.CurrentPath=Path;
            
            % Retrieve path where to save results
            app.SavePath=SavePath;
            
            % Retrieve time and date 
            app.Date_Start = Date_Start;
            
            % Storing default values in case no modification
            app.FileNames = app.FilesNameEditField.Value;
            app.SamplingRate = app.SamplingRateHzEditField.Value;
            app.Channels = app.ChannelnumbersEditField.Value;
            
            % Position the GUI at the center of the screen
            app.EEGParamGUIUIFigure.Position=Position;
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = MainApp;
                  
            % Common folder
            if length(FilesPath) > 1
                for k=1:length(FilesPath)-1
                   CommonLet(:,k) = ismember(strsplit(FilesPath{k},'\'),strsplit(FilesPath{k+1},'\')); 
                end
                SubPath = strsplit(FilesPath{1},'\');
                SubPath = strjoin(SubPath(sum(CommonLet,2)==length(FilesPath)-1),'\');
            else
                SubPath = FilesPath{1};
            end
            
            % Retrieving the files common name
            DirectoryTemp = dir([SubPath '\**/*' '.bdf']);
            assignin('base',"DirectoryTemp",DirectoryTemp)
            
            % Retrieving the common file name
            DirectoryTemp = DirectoryTemp(~contains({DirectoryTemp.name},'Preprocessed'));
            TempIntersect = strsplit(DirectoryTemp(1).name,app.ExtensionDropDown.Value);
            TempIntersect = TempIntersect{1};
            for k=1:length(DirectoryTemp)-1
                TempName = strsplit(DirectoryTemp(k+1).name,app.ExtensionDropDown.Value);
                TempName = TempName{1};
                TempIntersect = TempIntersect(ismember(lower(TempIntersect),lower(TempName)));
            end

            if ~isempty(TempIntersect)
                app.FilesNameEditField.Value = TempIntersect;
            else
                app.FilesNameEditField.Value = '';
            end
            
            % If Parameters were loaded in MAIN GUI
            if ~isempty(ParamPath)
                LoadedStruct = load([ParamPath '\EEGParamGUI.mat']);
                app.FilesNameEditField.Value = LoadedStruct.FileNames;
                app.ChannelnumbersEditField.Value = LoadedStruct.Channels;
                app.SamplingRateHzEditField.Value = LoadedStruct.SamplingRate; 
                    if isempty(LoadedStruct.Beginning)
                        app.BeginningEditField.Value = 0;
                    else
                        app.BeginningEditField.Value = LoadedStruct.Beginning;
                    end
                    if isempty(LoadedStruct.End)
                        app.EndEditField.Value = 0;
                    else
                        app.EndEditField.Value = LoadedStruct.End;
                    end
            end
        end

        % Value changed function: FilesNameEditField
        function FilesNameEditFieldValueChanged(app, event)
            app.FileNames = app.FilesNameEditField.Value;
        end

        % Value changed function: ChannelnumbersEditField
        function ChannelnumbersEditFieldValueChanged(app, event)
            app.Channels = app.ChannelnumbersEditField.Value;
        end

        % Value changed function: SamplingRateHzEditField
        function SamplingRateHzEditFieldValueChanged(app, event)
            app.SamplingRate = app.SamplingRateHzEditField.Value;     
        end

        % Value changed function: BeginningEditField
        function BeginningEditFieldValueChanged(app, event)
            app.Beginning = app.BeginningEditField.Value;
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            app.End = app.EndEditField.Value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if strcmp(value,'Yes')
                 ConfirmErase = questdlg('Are you sure you want to erase all excel files ?', ...
                'Confirm erase excel files','Yes','No','No');
            
                % Calling homemade function "Empty_Excel"
                if strcmp(ConfirmErase,'Yes')
                    Empty_Excel([app.CurrentPath '\Excel'],'xlsx') % ,'ExcelErasing_Parameters' only if exist in "parameters"
                    % THE FUNCTION NEEDS TO BE UPDATED !!! 
                end    
            end
        end

        % Callback function
        function TRIGGERSButtonPushed(app, event)
            app.DialogApp = TriggersGUI(app.EEGParamGUIUIFigure.Position,app.CurrentPath);
        end

        % Value changed function: ExtensionDropDown
        function ExtensionDropDownValueChanged(app, event)
            value = app.ExtensionDropDown.Value;
            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            % Send values back to main window by calling its public function
            updateMainGUI2(app.CallingApp);
            
            % Saving all data in the same .mat file
            ExpFilt = app.ExportFilt.Value;
            ExpClean = app.ExportClean.Value;
            ExpICAED = app.ExportICAED.Value;
            ExpICARej = app.ExportICARej.Value;
            ExpBDF = app.ExportBDF.Value;
            FileNames=app.FileNames;
            SamplingRate=app.SamplingRate;
            Channels=app.Channels;
            Beginning=app.Beginning;
            End=app.End;
            Extension=app.ExtensionDropDown.Value;
            save([app.SavePath '\Parameters\' app.Date_Start '\EEGParamGUI.mat'],'FileNames','SamplingRate','Channels','Beginning','End','Extension',...
                'ExpFilt','ExpClean','ExpICAED','ExpICARej','ExpBDF'); 
            
            % delete options dialog
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGParamGUIUIFigure = uifigure('Visible', 'off');
            app.EEGParamGUIUIFigure.Name = 'EEGParamGUI';
            app.MainTitleLabel = uilabel(app.EEGParamGUIUIFigure);
            app.MainTitleLabel.Text = 'EEG Parameters';
            app.BasicsPanel = uipanel(app.EEGParamGUIUIFigure);
            app.BasicsPanel.Title = 'Basics';
            app.FilesNameEditFieldLabel = uilabel(app.BasicsPanel);
            app.FilesNameEditFieldLabel.Text = 'Files Name';
            app.FilesNameEditField = uieditfield(app.BasicsPanel, 'text');
            app.FilesNameEditField.ValueChangedFcn = createCallbackFcn(app, @FilesNameEditFieldValueChanged, true);
            app.TextArea_2 = uitextarea(app.BasicsPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Either '; 'i) list all channels you want to include, e.g. 1 3 4 5 6 ... 64 or '; 'ii) a list of channels, e.g. 1:64 (from 1 to 64)'};
            app.ChannelnumbersEditFieldLabel = uilabel(app.BasicsPanel);
            app.ChannelnumbersEditFieldLabel.Text = 'Channel numbers';
            app.ChannelnumbersEditField = uieditfield(app.BasicsPanel, 'text');
            app.ChannelnumbersEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelnumbersEditFieldValueChanged, true);
            app.ChannelnumbersEditField.Value = '1:64';
            app.SamplingRateHzEditField_2Label = uilabel(app.BasicsPanel);
            app.SamplingRateHzEditField_2Label.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzEditField = uieditfield(app.BasicsPanel, 'numeric');
            app.SamplingRateHzEditField.Limits = [0 2048];
            app.SamplingRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateHzEditFieldValueChanged, true);
            app.SamplingRateHzEditField.Value = 1024;
            app.BeginningEditFieldLabel = uilabel(app.BasicsPanel);
            app.BeginningEditFieldLabel.Text = 'Beginning';
            app.BeginningEditField = uieditfield(app.BasicsPanel, 'numeric');
            app.BeginningEditField.ValueChangedFcn = createCallbackFcn(app, @BeginningEditFieldValueChanged, true);
            app.EndLabel = uilabel(app.BasicsPanel);
            app.EndLabel.Text = 'End';
            app.EndEditField = uieditfield(app.BasicsPanel, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.TextArea_3 = uitextarea(app.BasicsPanel);
            app.TextArea_3.Value = {'Restricting data length (in seconds), e.g. 12 1260 (0 means no restriction)'};
            app.ExtensionDropDownLabel = uilabel(app.BasicsPanel);
            app.ExtensionDropDownLabel.Text = 'Extension';
            app.ExtensionDropDown = uidropdown(app.BasicsPanel);
            app.ExtensionDropDown.Items = {'.bdf', '.set'};
            app.ExtensionDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtensionDropDownValueChanged, true);
            app.ExtensionDropDown.Value = '.bdf';
            app.TextArea_5 = uitextarea(app.BasicsPanel);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'If selecting .set extension, only the analyses will be performed'};
            app.TextArea = uitextarea(app.EEGParamGUIUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'The file name (e.g. "Session") must be common to all files ! If empty, will take all files.'};
            app.SAVEButton = uibutton(app.EEGParamGUIUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.TextArea_4 = uitextarea(app.EEGParamGUIUIFigure);
            app.TextArea_4.Value = {'Erase content of excel templates ? Careful, this cannot be undone !'};
            app.Switch = uiswitch(app.EEGParamGUIUIFigure, 'slider');
            app.Switch.Items = {'No', 'Yes'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'No';
            app.ExportPanel = uipanel(app.EEGParamGUIUIFigure);
            app.ExportPanel.Title = 'Export ';
            app.FilteredLabel = uilabel(app.ExportPanel);
            app.FilteredLabel.Text = '1. Filtered';
            app.ArtifactrejectedLabel = uilabel(app.ExportPanel);
            app.ArtifactrejectedLabel.Text = '2. Artifact rejected';
            app.ICAcomputedLabel = uilabel(app.ExportPanel);
            app.ICAcomputedLabel.Text = '3. ICA computed';
            app.ICArejectedLabel = uilabel(app.ExportPanel);
            app.ICArejectedLabel.Text = '4. ICA rejected';
            app.ExportFilt = uiswitch(app.ExportPanel, 'slider');
            app.ExportFilt.Items = {'No', 'Yes'};
            app.ExportFilt.Enable = 'off';
            app.ExportFilt.Value = 'Yes';
            app.ExportClean = uiswitch(app.ExportPanel, 'slider');
            app.ExportClean.Items = {'No', 'Yes'};
            app.ExportClean.Value = 'Yes';
            app.ExportICAED = uiswitch(app.ExportPanel, 'slider');
            app.ExportICAED.Items = {'No', 'Yes'};
            app.ExportICAED.Value = 'Yes';
            app.ExportICARej = uiswitch(app.ExportPanel, 'slider');
            app.ExportICARej.Items = {'No', 'Yes'};
            app.ExportICARej.Value = 'Yes';
            app.DatasetssetLabel = uilabel(app.ExportPanel);
            app.DatasetssetLabel.Text = 'Datasets (.set) :';
            app.FilesbdfLabel = uilabel(app.ExportPanel);
            app.FilesbdfLabel.Text = 'Files (.bdf):';
            app.PreprocessedLabel = uilabel(app.ExportPanel);
            app.PreprocessedLabel.Text = 'Preprocessed ';
            app.ExportBDF = uiswitch(app.ExportPanel, 'slider');
            app.ExportBDF.Items = {'No', 'Yes'};
            app.ExportBDF.Value = 'Yes';
            app.TextArea_6 = uitextarea(app.ExportPanel);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'Select the datasets/files you would like to save for each provided EEG file you will process. '};
            % Show the figure after all components are created
            app.EEGParamGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGParamGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGParamGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGParamGUIUIFigure)
        end
    end
end