classdef MainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainGUIUIFigure       matlab.ui.Figure
        MainTitleLabel        matlab.ui.control.Label
        Visual                matlab.ui.control.Label
        Visual2               matlab.ui.control.Label
        Development           matlab.ui.control.Label
        Development2          matlab.ui.control.Label
        TextAreaLabel         matlab.ui.control.Label
        MainInstructions      matlab.ui.control.TextArea
        PreprocessingButton   matlab.ui.control.Button
        STARTButton           matlab.ui.control.Button
        DesignButton          matlab.ui.control.Button
        ParametersButton      matlab.ui.control.Button
        LampDesign            matlab.ui.control.Lamp
        LampParam             matlab.ui.control.Lamp
        LampPreProc           matlab.ui.control.Lamp
        LampGO                matlab.ui.control.Lamp
        LOADPARAMETERSButton  matlab.ui.control.Button
        AnalysesButton        matlab.ui.control.Button
        LampGroupAnal         matlab.ui.control.Lamp
        Knob                  matlab.ui.control.DiscreteKnob
    end


    properties (Access = private)
        % To establish connection with dialog box
        DialogApp
        CurrentPath = pwd;
        Betweenlevels
        TimeFreq
        Path = '';
        WithinFactors
        BetweenFactors
        FilesPath
        Date_Start
        ParamPath
        SavePath
    end
    
    methods (Access = public)
    
        function updateMainGUI(app,WithinFactors,BetweenFactors,FilesPath,SavePath)

            
            % Adding path to dependencies
            % addpath(genpath([app.CurrentPath '\Functions']))
            
            % Changing the lamp color to green (i.e. data filed properly)
            app.LampDesign.Color = [0 1 0];
            
            % Enabling the next button
            app.ParametersButton.Enable ='on';
            
            % Saving Within/Between number of factors
            app.WithinFactors = WithinFactors;
            app.BetweenFactors = BetweenFactors;
            
            % Path of the data folders
            app.FilesPath = FilesPath;
            
            % Path where to save results
            app.SavePath = SavePath;
        end
        
        function updateMainGUI2(app)
            % Changing the lamp color to green (i.e. data filed properly)
            app.LampParam.Color = [0 1 0];
            
            % Enabling the next button
            app.PreprocessingButton.Enable ='on';
        end
        
        function updateMainGUI3(app)
            % Changing the lamp color to green (i.e. data filed properly)
            app.LampPreProc.Color = [0 1 0];
            
            % Enabling the next button
            app.AnalysesButton.Enable ='on';
        end
        
        function updateMainGUI4(app)
            % Changing the lamp color to green (i.e. data filed properly)
            app.LampGroupAnal.Color = [0 1 0];
                    
            % Turns on the GO button when enough data is collected
            if app.LampDesign.Color(2)==1 && app.LampParam.Color(2)==1 ...
                    && app.LampPreProc.Color(2)==1 && app.LampGroupAnal.Color(2)==1
                app.LampGO.Color=[0 1 0];
                app.STARTButton.Enable='on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Turn off useless warning
            warning off MATLAB:subscripting:noSubscriptsSpecified
            
            % Get dimensions of screen and position the GUI at the center of the screen
            Dims=get(groot,'Screensize');
            app.MainGUIUIFigure.Position = [Dims(3)/2-320 Dims(4)/2-240 640 480];
            
            % Retrieve the BetweenLevels mat file
            % app.Betweenlevels = load([app.CurrentPath '\Parameters\BetweenLevels.mat']);
            % Will need to establish the path of each group only once in the main! 
            
            % Get time and date
            app.Date_Start = clock;
            app.Date_Start = [num2str(app.Date_Start(3)) '_' num2str(app.Date_Start(2)) ...
                '_' num2str(app.Date_Start(1)) '-' num2str(app.Date_Start(4)) '_' num2str(app.Date_Start(5))];
            
%             assignin('base','app',app)    
%             % Progress bar setup
%             uiprogressdlg(app.MainGUIUIFigure,'Value',0.25)
        end

        % Button pushed function: LOADPARAMETERSButton
        function LOADPARAMETERSButtonPushed(app, event)

            % Retrieving the Parameters directory
            app.ParamPath = uigetdir(pwd,'Select the desired parameters folder to load data from');
           
            app.LampGO.Color=[0 1 0];
            app.LampDesign.Color=[0 1 0];
            app.LampParam.Color=[0 1 0];
            app.LampPreProc.Color=[0 1 0];
            app.LampGroupAnal.Color=[0 1 0];
            app.ParametersButton.Enable='on';
            app.PreprocessingButton.Enable='on';
            app.STARTButton.Enable='on';
            app.AnalysesButton.Enable='on';
            
            % Loading parameters
            load([app.ParamPath '\GUIDesign.mat'],'FilesPath','SavePath','WSfactors','BSfactors')
            load([app.ParamPath '\MainGUI.mat'],'Date_Start','Steps')
            app.FilesPath = FilesPath;
            app.SavePath = SavePath;
            app.Date_Start = Date_Start;
            app.Knob.Value = Steps;
            app.WithinFactors = WSfactors;
            app.BetweenFactors = BSfactors;
        end

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            % Open the options dialog and pass inputs
            app.DialogApp = DesignGUI(app,app.MainGUIUIFigure.Position,app.ParamPath,app.Date_Start);
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushed(app, event)
            app.DialogApp = EEGParamGUI(app,app.MainGUIUIFigure.Position,app.CurrentPath,app.ParamPath,app.FilesPath,app.Date_Start,app.SavePath);
        end

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
            % For examples, type in the command window:
            % appdesigner('DialogAppExample'); appdesigner('MainAppExample');
            % Open the options dialog and pass inputs
            app.DialogApp = PreprocessingGUI(app,app.MainGUIUIFigure.Position,app.CurrentPath,app.ParamPath,app.WithinFactors,...
                app.BetweenFactors,app.Date_Start,app.SavePath,app.Knob.Value);
        end

        % Button pushed function: AnalysesButton
        function AnalysesButtonPushed(app, event)
            % Open the options dialog and pass inputs
            app.DialogApp = StudyGUI(app,app.MainGUIUIFigure.Position,app.CurrentPath,app.ParamPath,app.WithinFactors,...
                app.BetweenFactors,app.Date_Start,app.SavePath);
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % Saving parameters as .mat file
            Date_Start = app.Date_Start;
            Steps = app.Knob.Value;
            save([app.SavePath '\Parameters\' app.Date_Start '\MainGUI.mat'],'Date_Start','Steps'); 
            
            if strcmpi(app.Knob.Value,'Preprocessing') || strcmpi(app.Knob.Value,'Both')
                % Run all scripts (MAIN, STUDY, LOG)
                MAIN(app,app.CurrentPath,app.Date_Start,app.SavePath)
            else
                % Only runs the STUDY script
                if sum(~app.SavePath)<1
                    GROUPSTUDY(app,app.SavePath,app.Date_Start)
                else
                    GROUPSTUDY(app,app.CurrentPath,app.Date_Start)
                end
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            if strcmpi(app.Knob.Value,'Preprocessing')
                % Changing the 4.Analyses lamp color to green 
                app.LampGroupAnal.Color = [0 1 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainGUIUIFigure = uifigure('Visible', 'off');
            app.MainGUIUIFigure.Name = 'MainGUI';
            app.MainTitleLabel = uilabel(app.MainGUIUIFigure);
            app.MainTitleLabel.Text = 'RESTINGLab';
            app.Visual = uilabel(app.MainGUIUIFigure);
            app.Visual.Text = '';
            app.Visual2 = uilabel(app.MainGUIUIFigure);
            app.Visual2.Text = '';
            app.Development = uilabel(app.MainGUIUIFigure);
            app.Development.Text = 'Developed at the Laboratory for Neurorehabilitation Science, University of Fribourg, CH, Version 0.62, 22.07.2020.';
            app.Development2 = uilabel(app.MainGUIUIFigure);
            app.Development2.Text = 'Author: Corentin Wicht (corentin.wicht@unifr.ch); Contributor : Christian Mancini (christian.mancini@unifr.ch)';
            app.TextAreaLabel = uilabel(app.MainGUIUIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.MainInstructions = uitextarea(app.MainGUIUIFigure);
            app.MainInstructions.Editable = 'off';
            app.MainInstructions.Value = {'Welcome to the 1st version of the RESTINGLab toolbox! '; 'This toolbox is dedicated to preprocessing of resting-state BioSemi 64 channels EEG data. '; ''; 'First, you should select what type of analysis to run (bottom knob)'; ''; 'Secondly, define the experimental DESIGN and PARAMETERS.'; ''; 'Then, you have the possibility to perform PREPROCESSING and ANALYSES of the files. '; ' '; 'By default the software is inactive. '; 'Parameters need to be defined before being able to press the START button.'; ''; 'If you already have parameters, you can load them by clicking on the LOAD PARAMETERS button.'; ''};
            app.PreprocessingButton = uibutton(app.MainGUIUIFigure, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Enable = 'off';
            app.PreprocessingButton.Text = '3.Preprocessing';
            app.STARTButton = uibutton(app.MainGUIUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.DesignButton = uibutton(app.MainGUIUIFigure, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Text = '1.Design';
            app.ParametersButton = uibutton(app.MainGUIUIFigure, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushed, true);
            app.ParametersButton.Enable = 'off';
            app.ParametersButton.Text = '2.Parameters';
            app.LampDesign = uilamp(app.MainGUIUIFigure);
            app.LampParam = uilamp(app.MainGUIUIFigure);
            app.LampPreProc = uilamp(app.MainGUIUIFigure);
            app.LampGO = uilamp(app.MainGUIUIFigure);
            app.LOADPARAMETERSButton = uibutton(app.MainGUIUIFigure, 'push');
            app.LOADPARAMETERSButton.ButtonPushedFcn = createCallbackFcn(app, @LOADPARAMETERSButtonPushed, true);
            app.LOADPARAMETERSButton.Text = {'LOAD'; 'PARAMETERS'};
            app.AnalysesButton = uibutton(app.MainGUIUIFigure, 'push');
            app.AnalysesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysesButtonPushed, true);
            app.AnalysesButton.Enable = 'off';
            app.AnalysesButton.Text = '4.Analyses';
            app.LampGroupAnal = uilamp(app.MainGUIUIFigure);
            app.Knob = uiknob(app.MainGUIUIFigure, 'discrete');
            app.Knob.Items = {'Preprocessing', 'Analyses', 'Both'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'Both';
            % Show the figure after all components are created
            app.MainGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainGUIUIFigure)
        end
    end
end