classdef PartAssignmentGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParticipantsAssignmentUIFigure  matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        MainTitleLabel                  matlab.ui.control.Label
        SAVEButton                      matlab.ui.control.Button
        LOADPARAMETERSButton            matlab.ui.control.Button
        ListofsubjectsnumbersifnoparametersEditField_3Label  matlab.ui.control.Label
        ListofsubjectsnumbersifnoparametersEditField  matlab.ui.control.EditField
        TextArea                        matlab.ui.control.TextArea
        ClearButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp   % Main app
        Conditions_Order
        Conditions_OrderCell
        CurrentPath
        Design
        ParticipantsList=[];
        RowNames
        ColumnNames
        NColumns
        FileTemp = 'Conditions_order.xlsx';
        DirectoryTemp
        SavePath
        Date_Start
        Loaded = 0;
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Path, Position, Design, Preloaded, Date_Start, SavePath)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = MainApp;
            
            % Retrieve path where to save results
            app.SavePath=SavePath;
            app.DirectoryTemp=SavePath;
            
            % Retrieve time and date 
            app.Date_Start = Date_Start;
            
            % Retrieve current path
            app.CurrentPath=Path;
            
            % Retrieve Design (1=withinfactors, 2=withinlevels, 3=betweenfactors, 4=betweenlevels)
            app.Design=Design;
            
            % Prepares column names vector
            app.ColumnNames={};

            % Within
            if ~isempty(Design{1,1})
                app.ColumnNames=[app.ColumnNames [Design{2,:}]]';
            else
                app.ColumnNames=[app.ColumnNames];
            end
            % Between
            if ~isempty(Design{3,1})
                app.ColumnNames=vertcat(app.ColumnNames,upper(Design{3,1}'));
            else
                app.ColumnNames=[app.ColumnNames];
            end
            
            % Number of columns
            app.NColumns=size(app.ColumnNames,1);
            
            % Position the GUI at the center of the screen
            app.ParticipantsAssignmentUIFigure.Position=Position;
            
             % Editing baseline table data based on the design settings 
             app.UITable.Data=cell(100,app.NColumns);
             
             % Building row names
             app.ParticipantsList=1:size(app.UITable.Data,1);
             for k=1:size(app.UITable.Data,1)
                app.RowNames{k}=sprintf('Subj_%d',app.ParticipantsList(k)); 
             end
             app.UITable.ColumnName=app.ColumnNames;
             app.UITable.RowName=app.RowNames;
             app.UITable.ColumnEditable=true;
             
%              % If parameters imported in MAIN GUI
%              if Preloaded
%                 % Disable the possibility to insert the list of participants
%                 app.ListofsubjectsnumbersifnoparametersEditField.Enable='off';
%                 
%                 % Reading data from the Conditions Order excel file
%                 app.Conditions_Order=readtable([app.CurrentPath '\Excel\Conditions_order.xlsx']);
%                 app.Conditions_OrderCell=table2cell(app.Conditions_Order);
%                 
%                 % Editing baseline table data 
%                 app.UITable.Data=app.Conditions_OrderCell(:,2:end);
%                 app.UITable.ColumnName=app.Conditions_Order.Properties.VariableNames(2:end);
%                 Numbers=regexp(app.Conditions_OrderCell(:,1),'\d*','Match');
%                 app.UITable.RowName=cellfun(@(x) ['Subj ' char(x{:})],Numbers,'UniformOutput',false); 
%              end
        end

        % Button pushed function: LOADPARAMETERSButton
        function LOADPARAMETERSButtonPushed(app, event)
            % Flag
            app.Loaded = 1;
            
            % Disable the possibility to insert the list of participants
            app.ListofsubjectsnumbersifnoparametersEditField.Enable='off';
            
            % Retrieving the Conditions Order file
            [app.FileTemp,app.DirectoryTemp] = uigetfile('Conditions_order.xlsx','Select the desired excel file containing conditions assignment');
            
            % Reading data from the Conditions Order excel file    
            % There might be more than 1 file, but since they should all be similar, I take the first one
            app.Conditions_Order=readtable([app.DirectoryTemp app.FileTemp]);
            app.Conditions_OrderCell=table2cell(app.Conditions_Order);
            
            % Editing baseline table data 
            app.UITable.Data=app.Conditions_OrderCell(:,2:end);
            app.UITable.ColumnName=app.Conditions_Order.Properties.VariableNames(2:end);
            if ischar(app.Conditions_OrderCell(1,1))
                Numbers=regexp(app.Conditions_OrderCell(:,1),'\d*','Match');
                app.UITable.RowName=cellfun(@(x) ['Subj ' char(x{:})],Numbers,'UniformOutput',false);
            else
                Numbers=cellfun(@(x) num2str(x),app.Conditions_OrderCell(:,1),'Uniformoutput',false);
                app.UITable.RowName=strcat(repmat({'Subj '},[length(Numbers),1]),Numbers);
            end
        end

        % Value changing function: 
        % ListofsubjectsnumbersifnoparametersEditField
        function ListofsubjectsnumbersifnoparametersEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.ParticipantsList=str2num(changingValue);
            
            RowNames={};
            for k=1:length(app.ParticipantsList)
                RowNames{k}=sprintf('Subj %d',app.ParticipantsList(k)); 
            end
            
            app.UITable.Data=cell(length(RowNames),app.NColumns);
            app.UITable.RowName=RowNames;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            if ~isempty(app.ParticipantsList)
                % Creating a new table (not imported from excel)
                ExportTable=cell2table([regexp(app.UITable.RowName,'\d*','Match') app.UITable.Data]);
                ExportTable.Properties.VariableNames=['Subjects' app.UITable.ColumnName'];
            else
%                 % Recreating the table (when imported from excel)
%                 ExportTable=cell2table([regexp(app.Conditions_OrderCell(:,1),'\d*','Match') app.UITable.Data]);
%                 ExportTable.Properties.VariableNames=app.Conditions_Order.Properties.VariableNames;
            end
            
            % Save modifications in Excel file  
            if app.Loaded
                DirectoryCond = app.DirectoryTemp;
            else
                DirectoryCond = [app.DirectoryTemp '\Excel\' app.Date_Start '\'];
            end
            FileCond = app.FileTemp;
            writetable(ExportTable,[DirectoryCond FileCond])
            save([app.SavePath '\Parameters\' app.Date_Start '\GUIPartList.mat'],'ExportTable','DirectoryCond','FileCond');
            
            % Send values back to main window by calling its public function
            updateDesignGUI(app.CallingApp,DirectoryCond,FileCond);
            
            % delete options dialog
            delete(app)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
             % Go back to baseline datatable
             app.UITable.ColumnName=app.ColumnNames;
             app.UITable.RowName=app.RowNames;
             app.UITable.Data=cell(100,app.NColumns);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParticipantsAssignmentUIFigure = uifigure;
            app.ParticipantsAssignmentUIFigure.Name = 'ParticipantsAssignment ';
            app.UITable = uitable(app.ParticipantsAssignmentUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'};
            app.UITable.RowName = {};
            app.MainTitleLabel = uilabel(app.ParticipantsAssignmentUIFigure);
            app.MainTitleLabel.Text = 'Subjects Assignment';
            app.SAVEButton = uibutton(app.ParticipantsAssignmentUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.LOADPARAMETERSButton = uibutton(app.ParticipantsAssignmentUIFigure, 'push');
            app.LOADPARAMETERSButton.ButtonPushedFcn = createCallbackFcn(app, @LOADPARAMETERSButtonPushed, true);
            app.LOADPARAMETERSButton.Text = 'LOAD PARAMETERS';
            app.ListofsubjectsnumbersifnoparametersEditField_3Label = uilabel(app.ParticipantsAssignmentUIFigure);
            app.ListofsubjectsnumbersifnoparametersEditField_3Label.Text = 'List of subjects numbers (if no parameters)';
            app.ListofsubjectsnumbersifnoparametersEditField = uieditfield(app.ParticipantsAssignmentUIFigure, 'text');
            app.ListofsubjectsnumbersifnoparametersEditField.ValueChangingFcn = createCallbackFcn(app, @ListofsubjectsnumbersifnoparametersEditFieldValueChanging, true);
            app.TextArea = uitextarea(app.ParticipantsAssignmentUIFigure);
            app.TextArea.Value = {'In the table below, columns with upper case-containing characters represent the BETWEEN-subject factor(s), the lower case ones represent each level of the WITHIN-subject factor(s). Press "LOAD PARAMETERS" to import data from the "Conditions_Order.xlsx" file in the Excel subfolder.'};
            app.ClearButton = uibutton(app.ParticipantsAssignmentUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PartAssignmentGUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParticipantsAssignmentUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParticipantsAssignmentUIFigure)
        end
    end
end