classdef StudyGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StudyGUIUIFigure               matlab.ui.Figure
        MainTitleLabel                 matlab.ui.control.Label
        InformationsstatisticsPanel    matlab.ui.container.Panel
        StatisticsDropDownLabel        matlab.ui.control.Label
        StatisticsDropDown             matlab.ui.control.DropDown
        NumberofPermutationsEditFieldLabel  matlab.ui.control.Label
        NumberofPermutationsEditField  matlab.ui.control.NumericEditField
        WithinsubjectSwitchLabel       matlab.ui.control.Label
        WithinsubjectSwitch            matlab.ui.control.Switch
        BetweensubjectSwitchLabel      matlab.ui.control.Label
        BetweensubjectSwitch           matlab.ui.control.Switch
        TFCESwitchLabel                matlab.ui.control.Label
        TFCESwitch                     matlab.ui.control.Switch
        CorrectedalphathresholdEditFieldLabel  matlab.ui.control.Label
        CorrectedalphathresholdEditField  matlab.ui.control.NumericEditField
        STUDYnameEditFieldLabel        matlab.ui.control.Label
        STUDYnameEditField             matlab.ui.control.EditField
        SAVEButton                     matlab.ui.control.Button
        AdditionalanalysesPanel        matlab.ui.container.Panel
        MicroStatesLabel               matlab.ui.control.Label
        MicroStatesSwitch              matlab.ui.control.Switch
        SettingsMSTButton              matlab.ui.control.Button
        HelpMST                        matlab.ui.control.Button
        ICclusteringLabel              matlab.ui.control.Label
        ICclusteringSwitch             matlab.ui.control.Switch
        SettingsICButton               matlab.ui.control.Button
        HelpIC                         matlab.ui.control.Button
        AlgorithmDropDownLabel         matlab.ui.control.Label
        AlgorithmDropDown              matlab.ui.control.DropDown
        SeparatecomputationbythebetweensubjfactorCheckBox  matlab.ui.control.CheckBox
        LowPassHzEditFieldLabel        matlab.ui.control.Label
        LowPassHzEditField             matlab.ui.control.NumericEditField
        ResampleHzEditFieldLabel       matlab.ui.control.Label
        ResampleHzEditField            matlab.ui.control.NumericEditField
        FrequencybandsanalysesLabel    matlab.ui.control.Label
        FrequencybandsanalysesSwitch   matlab.ui.control.Switch
        SubjectsPanel                  matlab.ui.container.Panel
        IncludeallsubjectsorselectedonesSwitchLabel  matlab.ui.control.Label
        IncludeallsubjectsorselectedonesSwitch  matlab.ui.control.Switch
    end


    properties (Access = private)
        CallingApp   % Main app
        DialogApp    % To establish connection with dialog box
        CurrentPath
        Date_Start
        SavePath
        CurrentPWD = pwd;
        FileCond
        DirectoryCond
        ProcessDataSTUDY
        
        % If parameters were loaded in the MAIN GUI
        ParamPath = [];
        
        % Saving Within/Between number of factors
        WithinFactors
        BetweenFactors
        
        % Default if the user do not click on the optional settings buttons
        MicroStatesParam
        ICClustParam = {'N','8','12','3','Y','1 0.05','0.01','Y','0.9','0.05','spec',''};
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Position, Path, ParamPath, WithinFactors, BetweenFactors, Date_Start, SavePath)
            % Retrieve current path
            app.CurrentPath=Path;
            
            % Retrieve path where to save results
            app.SavePath=SavePath;
                    
            % Path of loaded parameters (if any)
            app.ParamPath = ParamPath;
            
            % Retrieve time and date 
            app.Date_Start = Date_Start;
                                    
            % Default for the subjects to include in analysis
            load([app.SavePath '\Parameters\' app.Date_Start '\GUISubjects.mat'],'ProcessData')
            app.ProcessDataSTUDY = ProcessData;
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp=MainApp;
            
            % Position the GUI at the center of the screen
            app.StudyGUIUIFigure.Position=Position;
            
            % Display (or not) within/between factor(s) button
            app.WithinFactors = WithinFactors;
            app.BetweenFactors = BetweenFactors;
            if WithinFactors > 0
                app.WithinsubjectSwitchLabel.Enable = 'on';
                app.WithinsubjectSwitch.Enable = 'on';
            else
                app.WithinsubjectSwitch.Value = 'Off';
            end
            if BetweenFactors > 0
                app.BetweensubjectSwitchLabel.Enable = 'on';
                app.BetweensubjectSwitch.Enable = 'on';
            else
                app.BetweensubjectSwitch.Value = 'Off';
            end
            
            % If Parameters were loaded in MAIN GUI
            if ~isempty(ParamPath)
                            
                % Default for the subjects to include in analysis
                load([app.ParamPath '\GUISubjects.mat'],'ProcessData')
                app.ProcessDataSTUDY = ProcessData;
                
                LoadedStruct = load([ParamPath '\StudyGUI.mat']); 
                app.StatisticsDropDown.Value=LoadedStruct.StatsMethod;
                app.TFCESwitch.Value=LoadedStruct.TFCE;
                app.NumberofPermutationsEditField.Value=LoadedStruct.NPermut;
                app.WithinsubjectSwitch.Value=LoadedStruct.WithinSwitch;
                app.BetweensubjectSwitch.Value=LoadedStruct.BetweenSwitch;
                app.CorrectedalphathresholdEditField.Value=LoadedStruct.AlphaThresh;
                app.STUDYnameEditField.Value=LoadedStruct.StudyName;
                app.MicroStatesParam=LoadedStruct.MicroStatesParam;
                app.MicroStatesSwitch.Value=LoadedStruct.MicroStatesSwitch;
                app.ICClustParam=LoadedStruct.ICClustParam;
                app.ICclusteringSwitch.Value=LoadedStruct.ICclusteringSwitch;
                app.IncludeallsubjectsorselectedonesSwitch.Value=LoadedStruct.SubjSwitch;
                app.AlgorithmDropDown.Value=LoadedStruct.Algo;
                app.SeparatecomputationbythebetweensubjfactorCheckBox.Value=strcmpi(LoadedStruct.MicroStatesComputGroups,'on');
                app.LowPassHzEditField.Value = LoadedStruct.MSTLowPass;
                app.ResampleHzEditField.Value = LoadedStruct.MSTSamplingRate;
                app.FrequencybandsanalysesSwitch.Value = LoadedStruct.FreqBandsAnalyses;
                if strcmpi(LoadedStruct.MicroStatesSwitch,'Yes')
                    app.SettingsMSTButton.Enable = 'on';
                    app.AlgorithmDropDown.Enable = 'on';
                    app.AlgorithmDropDownLabel.Enable = 'on';
                    app.SeparatecomputationbythebetweensubjfactorCheckBox.Enable = 'on';
                    app.LowPassHzEditField.Enable = 'on';
                    app.LowPassHzEditFieldLabel.Enable = 'on';
                    app.ResampleHzEditField.Enable = 'on';
                    app.ResampleHzEditFieldLabel.Enable = 'on';
                end
                 if strcmpi(app.WithinsubjectSwitch.Value,'on')
                    app.WithinsubjectSwitchLabel.Enable = 'on';
                    app.WithinsubjectSwitch.Enable = 'on';
                end
                if strcmpi(app.BetweensubjectSwitch.Value,'on')
                    app.BetweensubjectSwitchLabel.Enable = 'on';
                    app.BetweensubjectSwitch.Enable = 'on';
                end
            end
        end

        % Value changed function: StatisticsDropDown
        function StatisticsDropDownValueChanged(app, event)
            value = app.StatisticsDropDown.Value;
            if strcmpi(value,'Permutations')
                app.NumberofPermutationsEditFieldLabel.Enable='on';
                app.NumberofPermutationsEditField.Enable='on';
            elseif strcmpi(value,'Bootstrap')
                app.NumberofPermutationsEditFieldLabel.Enable='off';
                app.NumberofPermutationsEditField.Enable='off';
            elseif strcmpi(value,'Parametric')
                app.NumberofPermutationsEditFieldLabel.Enable='off';
                app.NumberofPermutationsEditField.Enable='off';
                app.TFCEDropDown.Value = 'fdr';
            end
        end

        % Value changed function: CorrectedalphathresholdEditField
        function CorrectedalphathresholdEditFieldValueChanged(app, event)
            value = app.CorrectedalphathresholdEditField.Value;
        end

        % Value changed function: WithinsubjectSwitch
        function WithinsubjectSwitchValueChanged(app, event)
            value = app.WithinsubjectSwitch.Value;
        end

        % Value changed function: MicroStatesSwitch
        function MicroStatesSwitchValueChanged(app, event)
            value = app.MicroStatesSwitch.Value;
            if strcmpi(value,'Yes')
               app.AlgorithmDropDown.Enable = 'on';
               app.AlgorithmDropDownLabel.Enable = 'on';
               app.SettingsMSTButton.Enable = 'on';
               app.SeparatecomputationbythebetweensubjfactorCheckBox.Enable = 'on';
               app.LowPassHzEditField.Enable = 'on';
               app.LowPassHzEditFieldLabel.Enable = 'on';
               app.ResampleHzEditField.Enable = 'on';
               app.ResampleHzEditFieldLabel.Enable = 'on';
            else
               app.AlgorithmDropDown.Enable = 'off';
               app.AlgorithmDropDownLabel.Enable =  'off';
               app.SettingsMSTButton.Enable = 'off';
               app.SeparatecomputationbythebetweensubjfactorCheckBox.Enable = 'off';
               app.LowPassHzEditField.Enable = 'off';
               app.LowPassHzEditFieldLabel.Enable = 'off';
               app.ResampleHzEditField.Enable = 'off';
               app.ResampleHzEditFieldLabel.Enable = 'off';
            end
        end

        % Button pushed function: SettingsMSTButton
        function SettingsMSTButtonPushed(app, event)
          
        % Initialize the prompt 
        PromptMicroStates={'Normalize each dataset by average channel SD within each datatset [Y/N] ?',...
        'Minimal distance between GFP peaks',...
        'Number of GFP peaks maximally extracted per dataset',...
        'Discards maps with GFP exceeding [X] times the SD of the GFPs of all maps',...
        'Number of microstates to cluster (range)',...
        'Normalize across all datasets by average channel SD [Y/N] ?',...
        'Time range (is ms, separated by a space) to plot individual microstate segments over the GFP'};
    
        % Default parameters
        app.MicroStatesParam = {'N','10','1000','1','2:8','N', ''};
            
        % Prompt changes depending on selected algorithm
        if strcmpi(app.AlgorithmDropDown.Value,'kmeans')
             % Default parameters
            app.MicroStatesParam = [app.MicroStatesParam {'50','1000'}];
        
            % Prompt
            PromptMicroStates = [PromptMicroStates ...
            {'Number of random initializations (set it high!)',...
            'Maximum number of iterations for all restarts'}];
             
        elseif strcmpi(app.AlgorithmDropDown.Value,'modkmeans')
            % Default parameters
            app.MicroStatesParam = [app.MicroStatesParam {'50','1000','1e-06','2'}];
        
            % Prompt
            PromptMicroStates = [PromptMicroStates ...
            {'Number of random initializations (set it high!)',...
            'Maximum number of iterations for all restarts',...
            'Threshold of convergence based on relative change in noise variance',...
            ['Measure of fit, criterion for selecting best segmentation:' newline ...
            '1) Global explained variance (GEV)' newline ...
            '2) Cross-validation (CV)' newline ...
            '3) Dispersion (W)' newline ...
            '4) Krzanowski-Lai (KL)' newline ...
            '5) Normalised Krzanowski-Lai (KLnrm)']}];
             
        elseif strcmpi(app.AlgorithmDropDown.Value,'aahc') || strcmpi(app.AlgorithmDropDown.Value,'taahc')
            % Default parameters
            app.MicroStatesParam = [app.MicroStatesParam {'N','N'}];
        
            % Prompt
            PromptMicroStates = [PromptMicroStates ...
            {['TAAHC initialisation scheme for making the clustering determinate.' ...
            'Initialises by so every cluster consists of two samples, by agglomerating the most correlated samples [Y/N]'],...
            'Account for polarity? If set to N, the sign of correlation is ignored [Y/N]'}];
             
        elseif strcmpi(app.AlgorithmDropDown.Value,'varmicro')
            % Default parameters
            app.MicroStatesParam = [app.MicroStatesParam {'50','1000','1e-06','','0'}];
        
            % Prompt
            PromptMicroStates = [PromptMicroStates ...
            {'Number of random initializations (set it high!)',...
            'Maximum number of iterations for all restarts',...
            'Threshold of convergence based on relative change in noise variance',...
            'Prior variance of activations (default: average EEG channel variance)'...
            'Probability for having same microstate as last timepoint. Setting to zero turns smoothing off'}];
             
        end
         
        % Running prompt
        app.MicroStatesParam=inputdlg(PromptMicroStates,'MicroStates (MST1.0 toolbox)',1,app.MicroStatesParam);  
        end

        % Button pushed function: HelpMST
        function HelpMSTButtonPushed(app, event)
        Text=['Detailed information about parameter can be found in the authors publication:' ...
            newline 'https://www.biorxiv.org/content/biorxiv/early/2018/03/27/289850.full.pdf' newline newline ...
            'Also check the following publication for a direct comparison of the main algorithms:' newline ...
            'https://www.ncbi.nlm.nih.gov/pmc/articles/PMC6119811/'];
        
        app.DialogApp = InformationGUI(app.StudyGUIUIFigure.Position, Text);
        end

        % Button pushed function: SettingsICButton
        function SettingsICButtonPushed(app, event)
        PromptICCLust={'Should domains be based on anatomical ROIs "super voxels" [Y/N] ?',...
            'Spacing between grid points in the head model (in mm)',...
            'Standard deviation (SD) of 3D Gaussian representing each equivalent dipole location probability density',...
            'Radius in SD) to truncate each Gaussian (prevent spurious influences from distant dipoles in sparsely filled source regions)',...
            'Apply normalization to deep inside brain volumne or near brain surface dipole densities (unity mass within the brain volume) [Y/N] ?',...
            'Cuttoff Ratio to return the number of dipoles (1 = All) that have at least a X % (e.g. 0.05) chance of being in a domain',...
            'P-value for significance',...
            'Apply FDR correction [Y/N] ?',...
            'Maximum allowed correlation between projected spectral values and their cluster examplar (the lower the correlation, the greater the number of isolated clusters)',...
            'Dipole density cutoff in % for which to report corresponding anatomical areas (e.g. 0.05)',...
            'Type of measure to investigate (erp,ersp,spec,sift,...)',...
            'Frequency band to investigate (if empty, will run on the whole power spectrum)'};
       
        % Running prompt
        app.ICClustParam=inputdlg(PromptICCLust,'IC Clustering (Measure Projection Toolbox 1.661)',1,app.ICClustParam);  
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
        end

        % Value changed function: 
        % IncludeallsubjectsorselectedonesSwitch
        function IncludeallsubjectsorselectedonesSwitchValueChanged(app, event)
            if strcmpi(app.IncludeallsubjectsorselectedonesSwitch.Value,'Subset')
               load([app.SavePath '\Parameters\' app.Date_Start '\GUIPartList.mat'],'DirectoryCond','FileCond')
               app.FileCond = FileCond;
               app.DirectoryCond = DirectoryCond;
                
                % Open the options dialog and pass inputs
                app.DialogApp = SubjectsGUI(app.StudyGUIUIFigure.Position,app.CurrentPWD,app.ParamPath,...
                    app.WithinFactors,app.BetweenFactors,app.DirectoryCond,app.FileCond,app.Date_Start,app.SavePath,1);
            end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            % Saving parameters as .mat file
            StatsMethod=app.StatisticsDropDown.Value;
            TFCE=app.TFCESwitch.Value;
            NPermut=app.NumberofPermutationsEditField.Value;
            WithinSwitch=app.WithinsubjectSwitch.Value;
            BetweenSwitch=app.BetweensubjectSwitch.Value;
            AlphaThresh=app.CorrectedalphathresholdEditField.Value;
            StudyName=app.STUDYnameEditField.Value;
            MicroStatesSwitch=app.MicroStatesSwitch.Value;
            MicroStatesParam=app.MicroStatesParam;
            MSTLowPass = app.LowPassHzEditField.Value;
            MSTSamplingRate = app.ResampleHzEditField.Value;
            ICclusteringSwitch=app.ICclusteringSwitch.Value;
            SubjSwitch=app.IncludeallsubjectsorselectedonesSwitch.Value;
            Algo=app.AlgorithmDropDown.Value;
            ICClustParam=app.ICClustParam;
            FreqBandsAnalyses=app.FrequencybandsanalysesSwitch.Value;
            MicroStatesComputGroups=app.SeparatecomputationbythebetweensubjfactorCheckBox.Enable;
            if strcmpi(app.IncludeallsubjectsorselectedonesSwitch.Value,'Subset')
                save([app.SavePath '\Parameters\' app.Date_Start '\StudyGUI.mat'],'StatsMethod','TFCE','NPermut','WithinSwitch','Algo',...
                'BetweenSwitch','AlphaThresh','StudyName','MicroStatesSwitch','MicroStatesParam','MicroStatesComputGroups','MSTLowPass',...
                'MSTSamplingRate','ICclusteringSwitch','SubjSwitch','ICClustParam','FreqBandsAnalyses'); 
            else
                ProcessDataSTUDY = app.ProcessDataSTUDY;
                save([app.SavePath '\Parameters\' app.Date_Start '\StudyGUI.mat'],'StatsMethod','TFCE','NPermut','WithinSwitch','Algo',...
                'BetweenSwitch','AlphaThresh','StudyName','MicroStatesSwitch','MicroStatesParam','MicroStatesComputGroups','MSTLowPass',...
                'MSTSamplingRate','ICclusteringSwitch','SubjSwitch','ProcessDataSTUDY','ICClustParam','FreqBandsAnalyses'); 
            end
          
            
            % Send values back to main window by calling its public function
            updateMainGUI4(app.CallingApp);
            
            % delete options dialog
            delete(app)
        end

        % Value changed function: ICclusteringSwitch
        function ICclusteringSwitchValueChanged(app, event)
            value = app.ICclusteringSwitch.Value;
            if strcmpi(app.ICclusteringSwitch.Value,'Yes')
                app.SettingsICButton.Enable = 'on';
            else
                app.SettingsICButton.Enable = 'off';
            end
        end

        % Button pushed function: HelpIC
        function HelpICButtonPushed(app, event)
            Text=['Detailed information can be found in the authors publication:' ...
            newline 'https://www.ncbi.nlm.nih.gov/pmc/articles/PMC4082972/'];
            
            app.DialogApp = InformationGUI(app.StudyGUIUIFigure.Position, Text);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StudyGUIUIFigure = uifigure('Visible', 'off');
            app.StudyGUIUIFigure.Name = 'StudyUI';
            app.MainTitleLabel = uilabel(app.StudyGUIUIFigure);
            app.MainTitleLabel.Text = 'STUDY settings';
            app.InformationsstatisticsPanel = uipanel(app.StudyGUIUIFigure);
            app.InformationsstatisticsPanel.Title = 'Informations & statistics';
            app.StatisticsDropDownLabel = uilabel(app.InformationsstatisticsPanel);
            app.StatisticsDropDownLabel.Text = 'Statistics';
            app.StatisticsDropDown = uidropdown(app.InformationsstatisticsPanel);
            app.StatisticsDropDown.Items = {'Permutations'};
            app.StatisticsDropDown.ValueChangedFcn = createCallbackFcn(app, @StatisticsDropDownValueChanged, true);
            app.StatisticsDropDown.Value = 'Permutations';
            app.NumberofPermutationsEditFieldLabel = uilabel(app.InformationsstatisticsPanel);
            app.NumberofPermutationsEditFieldLabel.Text = 'Number of Permutations';
            app.NumberofPermutationsEditField = uieditfield(app.InformationsstatisticsPanel, 'numeric');
            app.NumberofPermutationsEditField.Limits = [100 10000];
            app.NumberofPermutationsEditField.Value = 5000;
            app.WithinsubjectSwitchLabel = uilabel(app.InformationsstatisticsPanel);
            app.WithinsubjectSwitchLabel.Enable = 'off';
            app.WithinsubjectSwitchLabel.Text = 'Within-subject';
            app.WithinsubjectSwitch = uiswitch(app.InformationsstatisticsPanel, 'slider');
            app.WithinsubjectSwitch.ValueChangedFcn = createCallbackFcn(app, @WithinsubjectSwitchValueChanged, true);
            app.WithinsubjectSwitch.Enable = 'off';
            app.WithinsubjectSwitch.Value = 'On';
            app.BetweensubjectSwitchLabel = uilabel(app.InformationsstatisticsPanel);
            app.BetweensubjectSwitchLabel.Enable = 'off';
            app.BetweensubjectSwitchLabel.Text = 'Between-subject';
            app.BetweensubjectSwitch = uiswitch(app.InformationsstatisticsPanel, 'slider');
            app.BetweensubjectSwitch.Enable = 'off';
            app.BetweensubjectSwitch.Value = 'On';
            app.TFCESwitchLabel = uilabel(app.InformationsstatisticsPanel);
            app.TFCESwitchLabel.Text = 'TFCE';
            app.TFCESwitch = uiswitch(app.InformationsstatisticsPanel, 'slider');
            app.TFCESwitch.Items = {'Yes', 'No'};
            app.TFCESwitch.Value = 'Yes';
            app.CorrectedalphathresholdEditFieldLabel = uilabel(app.InformationsstatisticsPanel);
            app.CorrectedalphathresholdEditFieldLabel.Text = 'Corrected alpha threshold';
            app.CorrectedalphathresholdEditField = uieditfield(app.InformationsstatisticsPanel, 'numeric');
            app.CorrectedalphathresholdEditField.ValueChangedFcn = createCallbackFcn(app, @CorrectedalphathresholdEditFieldValueChanged, true);
            app.CorrectedalphathresholdEditField.Value = 0.05;
            app.STUDYnameEditFieldLabel = uilabel(app.InformationsstatisticsPanel);
            app.STUDYnameEditFieldLabel.Text = 'STUDY name';
            app.STUDYnameEditField = uieditfield(app.InformationsstatisticsPanel, 'text');
            app.STUDYnameEditField.Value = 'STUDY';
            app.SAVEButton = uibutton(app.StudyGUIUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.AdditionalanalysesPanel = uipanel(app.StudyGUIUIFigure);
            app.AdditionalanalysesPanel.Title = 'Additional analyses';
            app.MicroStatesLabel = uilabel(app.AdditionalanalysesPanel);
            app.MicroStatesLabel.Text = '2) MicroStates';
            app.MicroStatesSwitch = uiswitch(app.AdditionalanalysesPanel, 'slider');
            app.MicroStatesSwitch.Items = {'Yes', 'No'};
            app.MicroStatesSwitch.ValueChangedFcn = createCallbackFcn(app, @MicroStatesSwitchValueChanged, true);
            app.MicroStatesSwitch.Value = 'No';
            app.SettingsMSTButton = uibutton(app.AdditionalanalysesPanel, 'push');
            app.SettingsMSTButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsMSTButtonPushed, true);
            app.SettingsMSTButton.Enable = 'off';
            app.SettingsMSTButton.Text = 'Settings';
            app.HelpMST = uibutton(app.AdditionalanalysesPanel, 'push');
            app.HelpMST.ButtonPushedFcn = createCallbackFcn(app, @HelpMSTButtonPushed, true);
            app.HelpMST.Text = '?';
            app.ICclusteringLabel = uilabel(app.AdditionalanalysesPanel);
            app.ICclusteringLabel.Text = '3) IC clustering';
            app.ICclusteringSwitch = uiswitch(app.AdditionalanalysesPanel, 'slider');
            app.ICclusteringSwitch.Items = {'Yes', 'No'};
            app.ICclusteringSwitch.ValueChangedFcn = createCallbackFcn(app, @ICclusteringSwitchValueChanged, true);
            app.ICclusteringSwitch.Value = 'No';
            app.SettingsICButton = uibutton(app.AdditionalanalysesPanel, 'push');
            app.SettingsICButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsICButtonPushed, true);
            app.SettingsICButton.Enable = 'off';
            app.SettingsICButton.Text = 'Settings';
            app.HelpIC = uibutton(app.AdditionalanalysesPanel, 'push');
            app.HelpIC.ButtonPushedFcn = createCallbackFcn(app, @HelpICButtonPushed, true);
            app.HelpIC.Text = '?';
            app.AlgorithmDropDownLabel = uilabel(app.AdditionalanalysesPanel);
            app.AlgorithmDropDownLabel.Enable = 'off';
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.AdditionalanalysesPanel);
            app.AlgorithmDropDown.Items = {'kmeans', 'modkmeans', 'aahc', 'taahc', 'varmicro'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Enable = 'off';
            app.AlgorithmDropDown.Value = 'modkmeans';
            app.SeparatecomputationbythebetweensubjfactorCheckBox = uicheckbox(app.AdditionalanalysesPanel);
            app.SeparatecomputationbythebetweensubjfactorCheckBox.Enable = 'off';
            app.SeparatecomputationbythebetweensubjfactorCheckBox.Text = 'Separate computation by the between-subj factor';
            app.LowPassHzEditFieldLabel = uilabel(app.AdditionalanalysesPanel);
            app.LowPassHzEditFieldLabel.Enable = 'off';
            app.LowPassHzEditFieldLabel.Text = 'LowPass (Hz)';
            app.LowPassHzEditField = uieditfield(app.AdditionalanalysesPanel, 'numeric');
            app.LowPassHzEditField.Limits = [0 Inf];
            app.LowPassHzEditField.Enable = 'off';
            app.ResampleHzEditFieldLabel = uilabel(app.AdditionalanalysesPanel);
            app.ResampleHzEditFieldLabel.Enable = 'off';
            app.ResampleHzEditFieldLabel.Text = 'Resample (Hz)';
            app.ResampleHzEditField = uieditfield(app.AdditionalanalysesPanel, 'numeric');
            app.ResampleHzEditField.Limits = [0 Inf];
            app.ResampleHzEditField.Enable = 'off';
            app.FrequencybandsanalysesLabel = uilabel(app.AdditionalanalysesPanel);
            app.FrequencybandsanalysesLabel.Text = '1) Frequency bands analyses';
            app.FrequencybandsanalysesSwitch = uiswitch(app.AdditionalanalysesPanel, 'slider');
            app.FrequencybandsanalysesSwitch.Items = {'Yes', 'No'};
            app.FrequencybandsanalysesSwitch.Value = 'Yes';
            app.SubjectsPanel = uipanel(app.StudyGUIUIFigure);
            app.SubjectsPanel.Title = 'Subjects';
            app.IncludeallsubjectsorselectedonesSwitchLabel = uilabel(app.SubjectsPanel);
            app.IncludeallsubjectsorselectedonesSwitchLabel.Text = 'Include all subjects or selected ones?';
            app.IncludeallsubjectsorselectedonesSwitch = uiswitch(app.SubjectsPanel, 'slider');
            app.IncludeallsubjectsorselectedonesSwitch.Items = {'All', 'Subset'};
            app.IncludeallsubjectsorselectedonesSwitch.ValueChangedFcn = createCallbackFcn(app, @IncludeallsubjectsorselectedonesSwitchValueChanged, true);
            app.IncludeallsubjectsorselectedonesSwitch.Value = 'All';
            % Show the figure after all components are created
            app.StudyGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StudyGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StudyGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StudyGUIUIFigure)
        end
    end
end