classdef SubjectsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SubjectsGUIUIFigure   matlab.ui.Figure
        MainTitleLabel        matlab.ui.control.Label
        UITable               matlab.ui.control.Table
        SAVEButton            matlab.ui.control.Button
        LoadParametersButton  matlab.ui.control.Button
        ClearButton           matlab.ui.control.Button
        SelectallButton       matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp   % Main app
        DialogApp
        Conditions_Order
        Conditions_OrderCell
        CurrentPath
        BSfactors
        WSfactors
        Date_Start
        SavePath
        IsSTUDY
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Position, Path, ParamPath, BSfactors, WSfactors, DirectoryCond, FileCond, Date_Start, SavePath, STUDY)
            % Retrieve current path
            app.CurrentPath=Path;
            
            % Retrieve path where to save results
            app.SavePath=SavePath;
            
            % Retrieve time and date 
            app.Date_Start = Date_Start;
            
            % Number of between-subject factors
            app.BSfactors=BSfactors;
            
            % Number of within-subject factors
            app.WSfactors=WSfactors;
            
            % Retrieve information about STUDY 
            app.IsSTUDY = STUDY;
            
            % Position the GUI at the center of the screen
            app.SubjectsGUIUIFigure.Position=Position;
            
            % Reading data from the Conditions Order excel file
            app.Conditions_Order=readtable([DirectoryCond FileCond]);
            app.Conditions_OrderCell=table2cell(app.Conditions_Order);
            
            % Editing baseline table data
            % Only BS
            if WSfactors == 0 && BSfactors > 0
                app.UITable.Data=repmat({false},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-1);
            % Only WS (NEED TO TEST THIS !!)
            elseif WSfactors > 0 && BSfactors == 0
                app.UITable.Data=repmat({false},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-1);
            %  WS + BS
            else
                app.UITable.Data=repmat({false},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-BSfactors-1);
            end
            app.UITable.Data(cellfun(@(x) isempty(x),app.Conditions_OrderCell(:,2:end-BSfactors)))={[]};
            app.UITable.ColumnName=app.Conditions_Order.Properties.VariableNames(2:end-BSfactors);
            Numbers = app.Conditions_OrderCell(:,1);
            app.UITable.RowName=strcat(repmat({'Subj '},[length(Numbers),1]),cellfun(@(x) num2str(x),Numbers,'UniformOutput',false));
            app.UITable.ColumnEditable=true;
            
            % If parameters imported in MAIN GUI
            if ~isempty(ParamPath) && ~STUDY
                load([ParamPath '\GUISubjects.mat'])
                app.UITable.Data=ProcessData; 
            elseif ~isempty(ParamPath) && STUDY
                load([ParamPath '\GUISubjectsSTUDY.mat'])
                app.UITable.Data=ProcessDataSTUDY; 
            end
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            % Possibility to load existing parameters
            [GuiSubjFile,GuiSubjPath] = uigetfile('GUISubjects.mat');
            load([GuiSubjPath GuiSubjFile])
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)

            if ~app.IsSTUDY
                % Saving properties in a .mat file     
                ProcessData=app.UITable.Data;
                save([app.SavePath '\Parameters\' app.Date_Start '\GUISubjects.mat'],'ProcessData');
            elseif app.IsSTUDY
                % Saving properties in a .mat file     
                ProcessDataSTUDY=app.UITable.Data;
                save([app.SavePath '\Parameters\' app.Date_Start '\GUISubjectsSTUDY.mat'],'ProcessDataSTUDY');
            end
            
            % delete options dialog
            delete(app)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Editing baseline table data
            % Only BS
            if app.WSfactors == 0 && app.BSfactors > 0
                app.UITable.Data=repmat({false},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-1);
            % Only WS (NEED TO TEST THIS !!)
            elseif app.WSfactors > 0 && app.BSfactors == 0
                app.UITable.Data=repmat({false},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-1);
            %  WS + BS
            else
                app.UITable.Data=repmat({false},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-app.BSfactors-1);
            end
            app.UITable.Data(cellfun(@(x) isempty(x),app.Conditions_OrderCell(:,2:end-app.BSfactors)))={[]};
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            % Editing baseline table data
            % Only BS
            if app.WSfactors == 0 && app.BSfactors > 0
                app.UITable.Data=repmat({true},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-1);
            % Only WS (NEED TO TEST THIS !!)
            elseif app.WSfactors > 0 && app.BSfactors == 0
                app.UITable.Data=repmat({true},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-1);
            %  WS + BS
            else
                app.UITable.Data=repmat({true},size(app.Conditions_OrderCell,1), size(app.Conditions_OrderCell,2)-app.BSfactors-1);
            end
            app.UITable.Data(cellfun(@(x) isempty(x),app.Conditions_OrderCell(:,2:end-app.BSfactors)))={[]};
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SubjectsGUIUIFigure = uifigure;
            app.SubjectsGUIUIFigure.Name = 'SubjectsGUI';
            app.MainTitleLabel = uilabel(app.SubjectsGUIUIFigure);
            app.MainTitleLabel.Text = 'SUBJECTS to process';
            app.UITable = uitable(app.SubjectsGUIUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.SAVEButton = uibutton(app.SubjectsGUIUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.LoadParametersButton = uibutton(app.SubjectsGUIUIFigure, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.ClearButton = uibutton(app.SubjectsGUIUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SelectallButton = uibutton(app.SubjectsGUIUIFigure, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SubjectsGUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SubjectsGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SubjectsGUIUIFigure)
        end
    end
end