classdef TriggersGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TriggersGUIUIFigure   matlab.ui.Figure
        MRKPATHButton         matlab.ui.control.Button
        MainTitleLabel        matlab.ui.control.Label
        UITable               matlab.ui.control.Table
        LOADPARAMETERSButton  matlab.ui.control.Button
        SAVEButton            matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
    end


    properties (Access = private)
        CallingApp   % Main app
        DialogApp
        MRKPath
        MRKFile
        DataMRK
        CurrentPath
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Position, Path)
            % Retrieve current path
            app.CurrentPath=Path;
            
            % Position the GUI at the center of the screen
            app.TriggersGUIUIFigure.Position=Position;
            
            % Filling in the table with empty cells and making it editable
            app.UITable.Data=cell(20,2);
            app.UITable.ColumnEditable=true;
        end

        % Button pushed function: MRKPATHButton
        function MRKPATHButtonPushed(app, event)
            % Loading path of the mrk file
            [app.MRKFile,app.MRKPath]=uigetfile('*.mrk','Click on the path of any .mrk file (should contain the list of ALL triggers)');
            
            % opening the .mrk file and capturing its data (trigger type and latency)
            delimiter = '\t';
            startRow = 2;
            formatSpec = '%q%q%q%[^\n\r]';
            fileID = fopen([app.MRKPath app.MRKFile],'r');
            app.DataMRK = textscan(fileID, formatSpec, 'Delimiter', delimiter, 'HeaderLines' ,startRow-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            fclose(fileID); 
            
            % Only keeping the 3rd column
            app.DataMRK=app.DataMRK{:,3};
            
            % Sending the data to the table
            app.UITable.Data=cell(length(app.DataMRK),2);
            app.UITable.Data=[app.DataMRK repmat({'New'},length(app.DataMRK),1)];
        end

        % Button pushed function: LOADPARAMETERSButton
        function LOADPARAMETERSButtonPushed(app, event)
            % Possibility to load existing parameters
            if exist([app.CurrentPath '\Parameters\GUITrigParam.mat'],'file')
                load ([app.CurrentPath '\Parameters\GUITrigParam.mat'])
                app.UITable.Data=DataMRK;
            else
                error ('You have not defined any parameters yet!')
            end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            % Saving properties in a .mat file     
            DataMRK=app.UITable.Data;
            save([app.CurrentPath '\Parameters\GUITrigParam.mat'],'DataMRK');
            
            % delete options dialog
            delete(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % When triggers have the same old names, they're automatically given the same new name! 
            Data=app.UITable.Data;
            OldTrig=str2num(cell2mat(Data(:,1)));
            UniqueOldTrig=unique(OldTrig);
            for k=1:length(UniqueOldTrig)
                FindUniques=find(OldTrig==UniqueOldTrig(k));
               if length(FindUniques)>1
                    Data(FindUniques',2)=Data(FindUniques(1),2);
               end
            end
            
            % Return values to the UITable
            app.UITable.Data=Data;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TriggersGUIUIFigure = uifigure;
            app.TriggersGUIUIFigure.Name = 'TriggersGUI';
            app.MRKPATHButton = uibutton(app.TriggersGUIUIFigure, 'push');
            app.MRKPATHButton.ButtonPushedFcn = createCallbackFcn(app, @MRKPATHButtonPushed, true);
            app.MRKPATHButton.Text = 'MRK PATH';
            app.MainTitleLabel = uilabel(app.TriggersGUIUIFigure);
            app.MainTitleLabel.Text = 'Triggers Renaming';
            app.UITable = uitable(app.TriggersGUIUIFigure);
            app.UITable.ColumnName = {'Old Triggers'; 'New Triggers'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.LOADPARAMETERSButton = uibutton(app.TriggersGUIUIFigure, 'push');
            app.LOADPARAMETERSButton.ButtonPushedFcn = createCallbackFcn(app, @LOADPARAMETERSButtonPushed, true);
            app.LOADPARAMETERSButton.Text = 'LOAD PARAMETERS';
            app.SAVEButton = uibutton(app.TriggersGUIUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.TextArea = uitextarea(app.TriggersGUIUIFigure);
            app.TextArea.Value = {'You can either decide to import a .mrk file or to enter manually the name of the old triggers in the first column. If done manually, you have to write them in their ORDER OF APPARITION, since we will use them as sequentially in the time-frequency decompositions.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = TriggersGUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TriggersGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TriggersGUIUIFigure)
        end
    end
end